classdef DataStoreManager < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    DatastoremanagerUIFigure       matlab.ui.Figure
    TabGroup                       matlab.ui.container.TabGroup
    CreateadatastoreTab            matlab.ui.container.Tab
    CreatedatastoreButton          matlab.ui.control.Button
    DatastorenameLabel             matlab.ui.control.Label
    DatastorenameEditField         matlab.ui.control.EditField
    DescriptionTextAreaLabel       matlab.ui.control.Label
    DescriptionTextArea            matlab.ui.control.TextArea
    SelectthemoviestoincludeListBoxLabel  matlab.ui.control.Label
    SelectthemoviestoincludeListBox  matlab.ui.control.ListBox
    SelectthepointstoincludeListBoxLabel  matlab.ui.control.Label
    SelectthepointstoincludeListBox  matlab.ui.control.ListBox
    StartframeEditFieldLabel       matlab.ui.control.Label
    StartframeEditField            matlab.ui.control.NumericEditField
    EndframeEditFieldLabel         matlab.ui.control.Label
    EndframeEditField              matlab.ui.control.NumericEditField
    CreateimagecacheuponcreationCheckBox  matlab.ui.control.CheckBox
    MessagesLabel                  matlab.ui.control.Label
    ImportadatastoreTab            matlab.ui.container.Tab
    SelectafiletoimportfromButton  matlab.ui.control.Button
    TextArea                       matlab.ui.control.TextArea
    SelectadatastoreDropDownLabel  matlab.ui.control.Label
    SelectadatastoreDropDown       matlab.ui.control.DropDown
    DescriptionTextArea_3Label     matlab.ui.control.Label
    DescriptionTextArea_3          matlab.ui.control.TextArea
    NumberofpointsEditFieldLabel   matlab.ui.control.Label
    NumberofpointsEditField        matlab.ui.control.NumericEditField
    SourcemoviesTextArea_2Label    matlab.ui.control.Label
    SourcemoviesTextArea_2         matlab.ui.control.TextArea
    ImportButton                   matlab.ui.control.Button
    MessagesTextAreaLabel          matlab.ui.control.Label
    MessagesTextArea               matlab.ui.control.TextArea
    ManagedatastoresTab            matlab.ui.container.Tab
    SampleImageAxes                matlab.ui.control.UIAxes
    SelectadatastoretomanageDropDownLabel  matlab.ui.control.Label
    SelectadatastoretomanageDropDown  matlab.ui.control.DropDown
    DescriptionTextArea_2Label     matlab.ui.control.Label
    DescriptionTextArea_2          matlab.ui.control.TextArea
    NumberofpointsLabel            matlab.ui.control.Label
    SourcemoviesTextAreaLabel      matlab.ui.control.Label
    SourcemoviesTextArea           matlab.ui.control.TextArea
    CacheimagesButton              matlab.ui.control.Button
    RemovecachedimagesButton       matlab.ui.control.Button
    DeletedatastoreButton          matlab.ui.control.Button
    GetsampleimageButton           matlab.ui.control.Button
    ExportdatastoreButton          matlab.ui.control.Button
    RenamedatastoreButton          matlab.ui.control.Button
    DLTdv8DeepLearningdatastorecreationandmanagementinterfaceLabel  matlab.ui.control.Label
    ExitButton                     matlab.ui.control.Button
  end

  
  properties (Access = private)
    mainApp % mainApp object
    importableDatastores % cell array of dataStores that could be imported
  end
  
  methods (Access = private)
    
    function [] = updateDSinfo(app)
      % update the interface in tab3 (manage datastores)
      sel=app.SelectadatastoretomanageDropDown.Value;
      if isempty(sel)==false
        app.DescriptionTextArea_2.Value=app.mainApp.dataStores{sel(1)}.description;
        app.NumberofpointsLabel.Text=['Number of points: ',num2str(app.mainApp.dataStores{sel(1)}.numPoints)];
        if app.mainApp.dataStores{sel(1)}.cached
          app.RemovecachedimagesButton.Enable=true;
          app.CacheimagesButton.Enable=false;
        else
          app.RemovecachedimagesButton.Enable=false;
          app.CacheimagesButton.Enable=true;
        end
        app.DeletedatastoreButton.Enable=true;
        app.GetsampleimageButton.Enable=true;
        
        app.SourcemoviesTextArea.Value=app.mainApp.dataStores{sel(1)}.movieFiles;
      else
        app.DescriptionTextArea_2.Value='';
        app.NumberofpointsLabel.Text='Number of points:';
        app.RemovecachedimagesButton.Enable=false;
        app.CacheimagesButton.Enable=false;
        app.SourcemoviesTextArea.Value='';
      end
      
      % clear image axis
      hold(app.SampleImageAxes,'off');
      plot(app.SampleImageAxes,[NaN NaN]);
      
    end
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, mainApp)
      app.mainApp = mainApp;
      
      % move
      mainAppPosition = app.mainApp.DLTdv8appUIFigure.Position;
      app.DatastoremanagerUIFigure.Position(1:2)=mainAppPosition(1:2)+[10,30];
      
      % populate the top tab
      % maximum movie frame value
      app.EndframeEditField.Value=mainApp.FrameNumberSlider.Limits(2);
      app.EndframeEditField.ValueDisplayFormat='%11.0f'; % move to default
      
      % list of possible points
      ptstring={};
      for i=1:mainApp.numpts
        ptstring{i}=num2str(i);
      end
      app.SelectthepointstoincludeListBox.Items=ptstring;
      
      % list the movies
      mvString={};
      for i=1:mainApp.nvid
        % video filename
        vname=getappdata(mainApp.handles{300+i},'fname');
        if isstr(vname) % simple string
          mvString{i}=vname;
        else
          mvString{i}=[vname.Path,filesep,vname.Name];
        end
      end
      app.SelectthemoviestoincludeListBox.Items=mvString;
      
      % deselect things in the first tab
      app.SelectthemoviestoincludeListBox.Value={};
      app.SelectthepointstoincludeListBox.Value={};
     
      
    end

    % Button pushed function: ExitButton
    function ExitButtonPushed(app, event)
      delete(app)
    end

    % Button pushed function: CreatedatastoreButton
    function CreatedatastoreButtonPushed(app, event)
      % validate setup
      a=app.DescriptionTextArea.Value;
      if isempty(a{1})
        app.MessagesLabel.Text='Datastores must have a description';
        return
      end
      if isempty(app.DatastorenameEditField.Value)
        app.MessagesLabel.Text='Datastores must have a name';
        return
      end
      % get list of selected points
      selectedPoints=[];
      for i=1:numel(app.SelectthepointstoincludeListBox.Value)
        selectedPoints(1,i)=str2num(app.SelectthepointstoincludeListBox.Value{i});
      end
      if isempty(selectedPoints)
        app.MessagesLabel.Text='Select some points to create a datastore';
        return
      end
      
      % get list of selected movies
      selectedMovies=[];
      movieFiles={};
      for i=1:numel(app.SelectthemoviestoincludeListBox.Value)
        selectedMovies(1,i)=find(startsWith(app.SelectthemoviestoincludeListBox.Items,app.SelectthemoviestoincludeListBox.Value{i}));
        movieFiles{i}=app.SelectthemoviestoincludeListBox.Items{selectedMovies(1,i)};
      end
      if isempty(selectedMovies)
        app.MessagesLabel.Text='Select some movies to create a datastore';
        return
      end
      
      % get the frame rate ratio for each movie
      for i=1:numel(selectedMovies)
        frameRateRatios{i}=app.mainApp.movFrameRateRatios(selectedMovies(1,i));
      end
      
      % complete points array
      uv=sp2full(app.mainApp.xypts);
      
      % get the points selected - the points array should have 2 columns
      % for every point, each selected movie gets its own cell array of
      % points
      points={};
      offsets={};
      for i=1:numel(selectedMovies)
        mNum=selectedMovies(i);
        nVid=app.mainApp.nvid;
        % indices of the selected points in the current movie
        pdx=sort([2*mNum+(selectedPoints-1)*nVid*2,2*mNum+(selectedPoints-1)*nVid*2-1]);
        points{i}=uv(:,pdx);
        idx=find(sum(isfinite(uv(:,pdx)),2)>0);
        idx(idx<app.StartframeEditField.Value)=[];
        idx(idx>app.EndframeEditField.Value)=[];
        offsets{i}=nanmedian(app.mainApp.offset(idx,mNum));
        points{i}(1:app.StartframeEditField.Value-1,:)=NaN;
        points{i}(app.EndframeEditField.Value+1:end,:)=NaN;
      end
      
      % create datastore
      app.MessagesLabel.Text='Creating the new datastore';
      ds=movieDataStore4(movieFiles,points,'offsets',offsets,'frameRateRatios',frameRateRatios);
      ds.name=app.DatastorenameEditField.Value;
      ds.description=app.DescriptionTextArea.Value;
      
      % build the image cache if called to do so
      if app.CreateimagecacheuponcreationCheckBox.Value
          app.MessagesLabel.Text='Creating the datastore image cache - please be patient!';
        ds.buildCache();
      end
      
      % export to the mainApp
      app.mainApp.dataStores{end+1}=ds;
      
      % reset the interface
      app.MessagesLabel.Text=['Datastore ',ds.name,' created!'];
      app.SelectthemoviestoincludeListBox.Value={};
      app.SelectthepointstoincludeListBox.Value={};
      app.DescriptionTextArea.Value='';
      app.DatastorenameEditField.Value='';
      
    end

    % Value changed function: SelectadatastoretomanageDropDown
    function SelectadatastoretomanageDropDownValueChanged(app, event)
      value = app.SelectadatastoretomanageDropDown.Value;
      updateDSinfo(app);
    end

    % Selection change function: TabGroup
    function TabGroupSelectionChanged(app, event)
      selectedTab = app.TabGroup.SelectedTab;
      
      % Third tab
      if strcmpi(selectedTab.Title,'Manage datastores')
        dsNames={};
        for i=1:numel(app.mainApp.dataStores)
          dsNames{i}=app.mainApp.dataStores{i}.name;
        end
        app.SelectadatastoretomanageDropDown.Items=dsNames;
        app.SelectadatastoretomanageDropDown.ItemsData=1:numel(dsNames);
        
        if isempty(dsNames)==false
          updateDSinfo(app)
        else
          app.DeletedatastoreButton.Enable=false;
          app.GetsampleimageButton.Enable=false;
          
        end
      end
      
    end

    % Button pushed function: DeletedatastoreButton
    function DeletedatastoreButtonPushed(app, event)
      sel=app.SelectadatastoretomanageDropDown.Value;
      app.mainApp.dataStores(sel(1))=[];
      TabGroupSelectionChanged(app,event)
      updateDSinfo(app);
    end

    % Button pushed function: GetsampleimageButton
    function GetsampleimageButtonPushed(app, event)
      sel=app.SelectadatastoretomanageDropDown.Value;
      myds=app.mainApp.dataStores{sel(1)};
      augState=myds.AugmentData;
      myds.AugmentData=false;
      mbs=myds.MiniBatchSize;
      myds.MiniBatchSize=1;
      myds.activePoints=1:myds.numPoints;
      iTable=myds.readNormal();
      myds.MiniBatchSize=mbs;
      myds.AugmentData=augState;
      
      hold(app.SampleImageAxes,'off')
      imagesc(app.SampleImageAxes,iTable.InputImage{1});
      hold(app.SampleImageAxes,'on')
      xlim(app.SampleImageAxes,[0 224])
      ylim(app.SampleImageAxes,[0 224])
      
      colors={'r','g','b','c','m','y'};
      colororder(app.SampleImageAxes,colors);
      r=iTable.ResponseImage{1};
      for i=1:size(r,3)-1
        [m,n]=find(r(:,:,i)==max(max(r(:,:,i))));
        if numel(m)==1
          h=plot(app.SampleImageAxes,n,m,'o','markersize',3,'color',colors{rem(i,numel(colors)-1)+1});
        end
        text(app.SampleImageAxes,5+10*(i-1),10,num2str(i),'Color',colors{rem(i,numel(colors)-1)+1},'FontSize',14,'FontWeight',"bold");
        
      end
      
    end

    % Button pushed function: RemovecachedimagesButton
    function RemovecachedimagesButtonPushed(app, event)
      sel=app.SelectadatastoretomanageDropDown.Value;
      app.mainApp.dataStores{sel(1)}.clearCache();
      updateDSinfo(app);
    end

    % Button pushed function: CacheimagesButton
    function CacheimagesButtonPushed(app, event)
      sel=app.SelectadatastoretomanageDropDown.Value;
      app.mainApp.dataStores{sel(1)}.buildCache();
      updateDSinfo(app);
    end

    % Button pushed function: SelectafiletoimportfromButton
    function SelectafiletoimportfromButtonPushed(app, event)

      [file,path] = uigetfile('*.mat','Select a matlab file containing a movie datastore');
      if file~=0
          mf=load([path,filesep,file]);
          app.MessagesTextArea.Value={'Loaded a mat-file'};
      else
          app.MessagesTextArea.Value={'File selection canceled'};
          return
      end
      try
          app.importableDatastores=mf.udExport.dataStores;
          app.TextArea.Value=[path,filesep,file];
          app.SelectadatastoreDropDown.Enable=true;
          app.SelectadatastoreDropDownLabel.Enable=true;
          dsList={};
          for i=1:numel(app.importableDatastores)
              dsList{i}=app.importableDatastores{i}.name;
          end
          app.ImportButton.Enable=true;
          app.SelectadatastoreDropDown.Items=dsList;
          app.SelectadatastoreDropDown.ItemsData=1:numel(dsList);
          app.SelectadatastoreDropDown.Value=1;
          SelectadatastoreDropDownValueChanged(app, event);

      catch
          app.TextArea.Value='';
          app.ImportButton.Enable=false;
          app.SelectadatastoreDropDown.Items={};
          app.SelectadatastoreDropDown.Enable=false;
          app.DescriptionTextArea_3.Value='';
          app.NumberofpointsEditField.Value=0;
          app.SourcemoviesTextArea_2.Value='';
          app.MessagesTextArea.Value={'No datastores found!'};
          
      end
      
    end

    % Value changed function: SelectadatastoreDropDown
    function SelectadatastoreDropDownValueChanged(app, event)
      value = app.SelectadatastoreDropDown.Value;
      app.DescriptionTextArea_3.Value=app.importableDatastores{value}.description;
      app.NumberofpointsEditField.Value=app.importableDatastores{value}.numPoints;
      app.SourcemoviesTextArea_2.Value=app.importableDatastores{value}.movieFiles;
    end

    % Button pushed function: ImportButton
    function ImportButtonPushed(app, event)
        app.mainApp.dataStores{end+1}=app.importableDatastores{app.SelectadatastoreDropDown.Value};
        app.MessagesTextArea.Value="Datastore imported!";
    end

    % Button pushed function: ExportdatastoreButton
    function ExportdatastoreButtonPushed(app, event)
      sel=app.SelectadatastoretomanageDropDown.Value;
      udExport.dataStores{1}=app.mainApp.dataStores{sel};
      udExport.dataStores{1}.resetReaders;
      
      [filename, pathname] = uiputfile('*.mat', 'Save datastore as');
      if filename~=0
        save([pathname,filesep,filename],"udExport",'-v7.3');
      end
    end

    % Button pushed function: RenamedatastoreButton
    function RenamedatastoreButtonPushed(app, event)
      sel=app.SelectadatastoretomanageDropDown.Value;
      newName=inputdlg('Edit the datastore name','Rename datastore',1,{app.mainApp.dataStores{sel}.name});
      if isempty(newName)
          return
      end
      app.mainApp.dataStores{sel}.name=newName{1};
      app.TabGroupSelectionChanged();
      
      
    end

    % Value changed function: DescriptionTextArea_2
    function DescriptionTextArea_2ValueChanged(app, event)
      value = app.DescriptionTextArea_2.Value;
      sel=app.SelectadatastoretomanageDropDown.Value;
      app.mainApp.dataStores{sel}.description=value;
      app.TabGroupSelectionChanged;
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.DatastoremanagerUIFigure = uifigure('Visible', 'off');
      app.DatastoremanagerUIFigure.Name = 'Datastore manager';
      app.TabGroup = uitabgroup(app.DatastoremanagerUIFigure);
      app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
      app.CreateadatastoreTab = uitab(app.TabGroup);
      app.CreateadatastoreTab.Tooltip = {''};
      app.CreateadatastoreTab.Title = 'Create a datastore';
      app.CreatedatastoreButton = uibutton(app.CreateadatastoreTab, 'push');
      app.CreatedatastoreButton.ButtonPushedFcn = createCallbackFcn(app, @CreatedatastoreButtonPushed, true);
      app.CreatedatastoreButton.Text = 'Create datastore!';
      app.DatastorenameLabel = uilabel(app.CreateadatastoreTab);
      app.DatastorenameLabel.Text = 'Datastore name:';
      app.DatastorenameEditField = uieditfield(app.CreateadatastoreTab, 'text');
      app.DescriptionTextAreaLabel = uilabel(app.CreateadatastoreTab);
      app.DescriptionTextAreaLabel.Text = 'Description:';
      app.DescriptionTextArea = uitextarea(app.CreateadatastoreTab);
      app.SelectthemoviestoincludeListBoxLabel = uilabel(app.CreateadatastoreTab);
      app.SelectthemoviestoincludeListBoxLabel.Text = 'Select the movies to include:';
      app.SelectthemoviestoincludeListBox = uilistbox(app.CreateadatastoreTab);
      app.SelectthemoviestoincludeListBox.Multiselect = 'on';
      app.SelectthemoviestoincludeListBox.Tooltip = {'Select one or more movies to include in the datastore'};
      app.SelectthemoviestoincludeListBox.Value = {'Item 1'};
      app.SelectthepointstoincludeListBoxLabel = uilabel(app.CreateadatastoreTab);
      app.SelectthepointstoincludeListBoxLabel.Text = 'Select the points to include:';
      app.SelectthepointstoincludeListBox = uilistbox(app.CreateadatastoreTab);
      app.SelectthepointstoincludeListBox.Multiselect = 'on';
      app.SelectthepointstoincludeListBox.Tooltip = {'Select the points to include'};
      app.SelectthepointstoincludeListBox.Value = {'Item 1'};
      app.StartframeEditFieldLabel = uilabel(app.CreateadatastoreTab);
      app.StartframeEditFieldLabel.Text = 'Start frame:';
      app.StartframeEditField = uieditfield(app.CreateadatastoreTab, 'numeric');
      app.StartframeEditField.Limits = [0 Inf];
      app.StartframeEditField.RoundFractionalValues = 'on';
      app.StartframeEditField.ValueDisplayFormat = '%11.0f';
      app.StartframeEditField.Value = 1;
      app.EndframeEditFieldLabel = uilabel(app.CreateadatastoreTab);
      app.EndframeEditFieldLabel.Text = 'End frame';
      app.EndframeEditField = uieditfield(app.CreateadatastoreTab, 'numeric');
      app.EndframeEditField.Limits = [0 Inf];
      app.EndframeEditField.RoundFractionalValues = 'on';
      app.EndframeEditField.ValueDisplayFormat = '%11.0f';
      app.EndframeEditField.Value = 1;
      app.CreateimagecacheuponcreationCheckBox = uicheckbox(app.CreateadatastoreTab);
      app.CreateimagecacheuponcreationCheckBox.Tooltip = {'Cached datastores have their images stored internally; this can take up lots of RAM if there are many frames with points, but also makes the datastore more portable among projects and computers.'};
      app.CreateimagecacheuponcreationCheckBox.Text = 'Create image cache upon creation';
      app.MessagesLabel = uilabel(app.CreateadatastoreTab);
      app.MessagesLabel.Text = 'Messages:';
      app.ImportadatastoreTab = uitab(app.TabGroup);
      app.ImportadatastoreTab.Tooltip = {''};
      app.ImportadatastoreTab.Title = 'Import a datastore';
      app.SelectafiletoimportfromButton = uibutton(app.ImportadatastoreTab, 'push');
      app.SelectafiletoimportfromButton.ButtonPushedFcn = createCallbackFcn(app, @SelectafiletoimportfromButtonPushed, true);
      app.SelectafiletoimportfromButton.Text = 'Select a file to import from';
      app.TextArea = uitextarea(app.ImportadatastoreTab);
      app.TextArea.Editable = 'off';
      app.SelectadatastoreDropDownLabel = uilabel(app.ImportadatastoreTab);
      app.SelectadatastoreDropDownLabel.Enable = 'off';
      app.SelectadatastoreDropDownLabel.Text = 'Select a datastore';
      app.SelectadatastoreDropDown = uidropdown(app.ImportadatastoreTab);
      app.SelectadatastoreDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectadatastoreDropDownValueChanged, true);
      app.SelectadatastoreDropDown.Enable = 'off';
      app.DescriptionTextArea_3Label = uilabel(app.ImportadatastoreTab);
      app.DescriptionTextArea_3Label.Text = 'Description';
      app.DescriptionTextArea_3 = uitextarea(app.ImportadatastoreTab);
      app.DescriptionTextArea_3.Editable = 'off';
      app.NumberofpointsEditFieldLabel = uilabel(app.ImportadatastoreTab);
      app.NumberofpointsEditFieldLabel.Text = 'Number of points';
      app.NumberofpointsEditField = uieditfield(app.ImportadatastoreTab, 'numeric');
      app.NumberofpointsEditField.ValueDisplayFormat = '%11.0f';
      app.SourcemoviesTextArea_2Label = uilabel(app.ImportadatastoreTab);
      app.SourcemoviesTextArea_2Label.Text = 'Source movies:';
      app.SourcemoviesTextArea_2 = uitextarea(app.ImportadatastoreTab);
      app.SourcemoviesTextArea_2.Editable = 'off';
      app.ImportButton = uibutton(app.ImportadatastoreTab, 'push');
      app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
      app.ImportButton.Enable = 'off';
      app.ImportButton.Text = 'Import!';
      app.MessagesTextAreaLabel = uilabel(app.ImportadatastoreTab);
      app.MessagesTextAreaLabel.Text = 'Messages:';
      app.MessagesTextArea = uitextarea(app.ImportadatastoreTab);
      app.MessagesTextArea.Editable = 'off';
      app.ManagedatastoresTab = uitab(app.TabGroup);
      app.ManagedatastoresTab.Tooltip = {''};
      app.ManagedatastoresTab.Title = 'Manage datastores';
      app.SampleImageAxes = uiaxes(app.ManagedatastoresTab);
      title(app.SampleImageAxes, 'Sample image')
      xlabel(app.SampleImageAxes, '')
      ylabel(app.SampleImageAxes, '')
      app.SampleImageAxes.XTick = [];
      app.SampleImageAxes.YTick = [];
      app.SelectadatastoretomanageDropDownLabel = uilabel(app.ManagedatastoresTab);
      app.SelectadatastoretomanageDropDownLabel.Text = 'Select a datastore to manage:';
      app.SelectadatastoretomanageDropDown = uidropdown(app.ManagedatastoresTab);
      app.SelectadatastoretomanageDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectadatastoretomanageDropDownValueChanged, true);
      app.DescriptionTextArea_2Label = uilabel(app.ManagedatastoresTab);
      app.DescriptionTextArea_2Label.Text = 'Description:';
      app.DescriptionTextArea_2 = uitextarea(app.ManagedatastoresTab);
      app.DescriptionTextArea_2.ValueChangedFcn = createCallbackFcn(app, @DescriptionTextArea_2ValueChanged, true);
      app.DescriptionTextArea_2.Tooltip = {'The datastore description - edit the text as needed.'};
      app.NumberofpointsLabel = uilabel(app.ManagedatastoresTab);
      app.NumberofpointsLabel.Text = 'Number of points:';
      app.SourcemoviesTextAreaLabel = uilabel(app.ManagedatastoresTab);
      app.SourcemoviesTextAreaLabel.Text = 'Source movies:';
      app.SourcemoviesTextArea = uitextarea(app.ManagedatastoresTab);
      app.SourcemoviesTextArea.Editable = 'off';
      app.CacheimagesButton = uibutton(app.ManagedatastoresTab, 'push');
      app.CacheimagesButton.ButtonPushedFcn = createCallbackFcn(app, @CacheimagesButtonPushed, true);
      app.CacheimagesButton.Enable = 'off';
      app.CacheimagesButton.Text = 'Cache images';
      app.RemovecachedimagesButton = uibutton(app.ManagedatastoresTab, 'push');
      app.RemovecachedimagesButton.ButtonPushedFcn = createCallbackFcn(app, @RemovecachedimagesButtonPushed, true);
      app.RemovecachedimagesButton.Enable = 'off';
      app.RemovecachedimagesButton.Text = 'Remove cached images';
      app.DeletedatastoreButton = uibutton(app.ManagedatastoresTab, 'push');
      app.DeletedatastoreButton.ButtonPushedFcn = createCallbackFcn(app, @DeletedatastoreButtonPushed, true);
      app.DeletedatastoreButton.Text = 'Delete datastore';
      app.GetsampleimageButton = uibutton(app.ManagedatastoresTab, 'push');
      app.GetsampleimageButton.ButtonPushedFcn = createCallbackFcn(app, @GetsampleimageButtonPushed, true);
      app.GetsampleimageButton.Text = 'Get sample image';
      app.ExportdatastoreButton = uibutton(app.ManagedatastoresTab, 'push');
      app.ExportdatastoreButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdatastoreButtonPushed, true);
      app.ExportdatastoreButton.Text = 'Export datastore';
      app.RenamedatastoreButton = uibutton(app.ManagedatastoresTab, 'push');
      app.RenamedatastoreButton.ButtonPushedFcn = createCallbackFcn(app, @RenamedatastoreButtonPushed, true);
      app.RenamedatastoreButton.Text = 'Re-name datastore';
      app.DLTdv8DeepLearningdatastorecreationandmanagementinterfaceLabel = uilabel(app.DatastoremanagerUIFigure);
      app.DLTdv8DeepLearningdatastorecreationandmanagementinterfaceLabel.Text = 'DLTdv8 Deep Learning datastore creation and management interface';
      app.ExitButton = uibutton(app.DatastoremanagerUIFigure, 'push');
      app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
      app.ExitButton.Tooltip = {'Exit the datastore interface and go back to DLTdv8.'};
      app.ExitButton.Text = 'Exit';
      % Show the figure after all components are created
      app.DatastoremanagerUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = DataStoreManager(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.DatastoremanagerUIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.DatastoremanagerUIFigure)
    end
  end
end