classdef NetworkApplicationInterface < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    NetworkapplicationinterfaceUIFigure  matlab.ui.Figure
    TabGroup                        matlab.ui.container.TabGroup
    Applytoasinglemovie2DTab        matlab.ui.container.Tab
    SelectanetworktoapplyDropDownLabel  matlab.ui.control.Label
    SelectanetworktoapplyDropDown   matlab.ui.control.DropDown
    DescriptionTextArea_2Label      matlab.ui.control.Label
    DescriptionTextArea_2           matlab.ui.control.TextArea
    NumberofpointsLabel             matlab.ui.control.Label
    NetworktypeLabel_2              matlab.ui.control.Label
    TrainingstatusLabel_4           matlab.ui.control.Label
    TrainingstatusTextArea          matlab.ui.control.TextArea
    SelectthetargetvideoDropDownLabel  matlab.ui.control.Label
    SelectthetargetvideoDropDown    matlab.ui.control.DropDown
    StartframeEditFieldLabel        matlab.ui.control.Label
    StartframeEditField             matlab.ui.control.NumericEditField
    EndframeEditFieldLabel          matlab.ui.control.Label
    EndframeEditField               matlab.ui.control.NumericEditField
    ApplynetworkButton              matlab.ui.control.Button
    MinimummatchqualityEditFieldLabel  matlab.ui.control.Label
    MinimummatchqualityEditField    matlab.ui.control.NumericEditField
    OverwriteexistingdataCheckBox   matlab.ui.control.CheckBox
    StopButton                      matlab.ui.control.StateButton
    RespectDLTresidualthresholdCheckBox  matlab.ui.control.CheckBox
    VisualizenetworkoutputCheckBox  matlab.ui.control.CheckBox
    Applytomultiplemovies3DTab      matlab.ui.container.Tab
    NumberofpointsLabel_2           matlab.ui.control.Label
    NetworktypeLabel_3              matlab.ui.control.Label
    SelectanetworktoapplyDropDown_2Label  matlab.ui.control.Label
    SelectanetworktoapplyDropDown_2  matlab.ui.control.DropDown
    DescriptionTextArea_3Label      matlab.ui.control.Label
    DescriptionTextArea_3           matlab.ui.control.TextArea
    TrainingstatusTextArea_2Label   matlab.ui.control.Label
    TrainingstatusTextArea_2        matlab.ui.control.TextArea
    SelectthetargetvideoDropDown_2Label  matlab.ui.control.Label
    SelectthetargetvideoDropDown_2  matlab.ui.control.DropDown
    OverwriteexistingdataCheckBox_2  matlab.ui.control.CheckBox
    RespectDLTresidualthresholdCheckBox_2  matlab.ui.control.CheckBox
    ApplynetworksButton             matlab.ui.control.Button
    StopButton_2                    matlab.ui.control.StateButton
    StartframeEditField_2Label      matlab.ui.control.Label
    StartframeEditField_2           matlab.ui.control.NumericEditField
    EndframeEditField_2Label        matlab.ui.control.Label
    EndframeEditField_2             matlab.ui.control.NumericEditField
    MinimummatchqualityEditField_2Label  matlab.ui.control.Label
    MinimummatchqualityEditField_2  matlab.ui.control.NumericEditField
    videonetworkprocessinglistTextAreaLabel  matlab.ui.control.Label
    videonetworkprocessinglistTextArea  matlab.ui.control.TextArea
    AddtoprocessinglistButton       matlab.ui.control.Button
    ClearprocessinglistButton       matlab.ui.control.Button
    RefinepointsTab                 matlab.ui.container.Tab
    NumberofpointsLabel_3           matlab.ui.control.Label
    RunQueueButton                  matlab.ui.control.Button
    OverwriteexistingdataCheckBox_3  matlab.ui.control.CheckBox
    StopButton_3                    matlab.ui.control.StateButton
    RespectDLTresidualthresholdCheckBox_3  matlab.ui.control.CheckBox
    SelectanetworktoapplyDropDown_3Label  matlab.ui.control.Label
    SelectanetworktoapplyDropDown_3  matlab.ui.control.DropDown
    DescriptionTextArea_4Label      matlab.ui.control.Label
    DescriptionTextArea_4           matlab.ui.control.TextArea
    TrainingstatusTextArea_3Label   matlab.ui.control.Label
    TrainingstatusTextArea_3        matlab.ui.control.TextArea
    StartframeEditField_3Label      matlab.ui.control.Label
    StartframeEditField_3           matlab.ui.control.NumericEditField
    EndframeEditField_3Label        matlab.ui.control.Label
    EndframeEditField_3             matlab.ui.control.NumericEditField
    MinimummatchqualityEditField_3Label  matlab.ui.control.Label
    MinimummatchqualityEditField_3  matlab.ui.control.NumericEditField
    SelectthetargetpointDropDown_2Label  matlab.ui.control.Label
    SelectthetargetpointDropDown_2  matlab.ui.control.DropDown
    SelectthetargetvideoDropDown_3Label  matlab.ui.control.Label
    SelectthetargetvideoDropDown_3  matlab.ui.control.DropDown
    AddtoQueueButton                matlab.ui.control.Button
    ProcessingqueueLabel            matlab.ui.control.Label
    ProcessingqueueTextArea         matlab.ui.control.TextArea
    ClearQueueButton                matlab.ui.control.Button
    DLTdv8DeepLearningnetworkapplicationinterfaceLabel  matlab.ui.control.Label
    ExitButton                      matlab.ui.control.Button
  end

  
  properties (Access = private)
    mainApp % mainApp object
    movieToNet % cell array matching movies and networks for group application in the multi-movie tab
    refineQueue % cell array matching movies, networks, and meta-data for application in the refine tab
  end
  
  methods (Access = private)
    
    function [] = updateNetinfo(app)
      % update the interface in the various tabs
      
      % First tab
      if strcmpi(app.TabGroup.SelectedTab.Title,'Apply to a single movie (2D)')
        sel=app.SelectanetworktoapplyDropDown.Value;
        if isempty(sel)==false
          app.DescriptionTextArea_2.Value=app.mainApp.deepLearningNetworks{sel(1)}{2}.description;
          app.NumberofpointsLabel.Text=['Number of points: ',num2str(app.mainApp.deepLearningNetworks{sel(1)}{2}.numPoints)];
          
          % type: 1 = multi-point with cropping, 2 = multi-point no cropping,
          % 3 = single point cropping
          if app.mainApp.deepLearningNetworks{sel(1)}{2}.type==1
            typeStr='multi-point with cropping';
          elseif app.mainApp.deepLearningNetworks{sel(1)}{2}.type==2
            typeStr='multi-point without cropping';
          else
            typeStr='single point with cropping';
          end
          
          app.NetworktypeLabel_2.Text=['Network type: ',typeStr];
          if app.mainApp.deepLearningNetworks{sel(1)}{2}.trained==false
            app.TrainingstatusTextArea.Value="untrained";
          else
            tString={};
            tString{1}='Trained:';
            tString{2}=sprintf('Final training RMSE: %.2f',app.mainApp.deepLearningNetworks{sel(1)}{2}.trainingRMSE);
            tString{3}=sprintf('Final training loss: %.2f',app.mainApp.deepLearningNetworks{sel(1)}{2}.trainingLoss);
            app.TrainingstatusTextArea.Value=tString;
          end
          %           app.DeletenetworkButton.Enable=true;
          %           app.ExportnetworkButton.Enable=true;
          
        else
          app.DescriptionTextArea_2.Value='';
          app.NumberofpointsLabel.Text='Number of points:';
          app.NetworktypeLabel_2.Text='Network type: ';
          app.TrainingstatusTextArea.Value='';
          %           app.DeletenetworkButton.Enable=false;
          %           app.ExportnetworkButton.Enable=false;
          
        end
        % tab 2
      elseif strcmpi(app.TabGroup.SelectedTab.Title,'Apply to multiple movies (3D)')
        sel=app.SelectanetworktoapplyDropDown_2.Value;
        if isempty(sel)==false
          app.NumberofpointsLabel_2.Text=['Number of points: ',num2str(app.mainApp.deepLearningNetworks{sel(1)}{2}.numPoints)];
          app.DescriptionTextArea_3.Value=app.mainApp.deepLearningNetworks{sel(1)}{2}.description;
          
          % type: 1 = multi-point with cropping, 2 = multi-point no cropping,
          % 3 = single point cropping
          if app.mainApp.deepLearningNetworks{sel(1)}{2}.type==1
            typeStr='multi-point with cropping';
          elseif app.mainApp.deepLearningNetworks{sel(1)}{2}.type==2
            typeStr='multi-point without cropping';
          else
            typeStr='single point with cropping';
          end
          app.NetworktypeLabel_3.Text=['Network type: ',typeStr];
          
          if app.mainApp.deepLearningNetworks{sel(1)}{2}.trained==false
            app.TrainingstatusTextArea_2.Value="untrained";
          else
            tString={};
            tString{1}='Trained:';
            tString{2}=sprintf('Final training RMSE: %.2f',app.mainApp.deepLearningNetworks{sel(1)}{2}.trainingRMSE);
            tString{3}=sprintf('Final training loss: %.2f',app.mainApp.deepLearningNetworks{sel(1)}{2}.trainingLoss);
            app.TrainingstatusTextArea_2.Value=tString;
          end
          
        end
      elseif strcmpi(app.TabGroup.SelectedTab.Title,'Refine points')
        sel=app.SelectanetworktoapplyDropDown_3.Value;
        if isempty(sel)==false
          app.NumberofpointsLabel_3.Text=['Number of points: ',num2str(app.mainApp.deepLearningNetworks{sel(1)}{2}.numPoints)];
          app.DescriptionTextArea_4.Value=app.mainApp.deepLearningNetworks{sel(1)}{2}.description;
          
          % type: 1 = multi-point with cropping, 2 = multi-point no cropping,
          % 3 = single point cropping
          if app.mainApp.deepLearningNetworks{sel(1)}{2}.type==1
            typeStr='multi-point with cropping';
          elseif app.mainApp.deepLearningNetworks{sel(1)}{2}.type==2
            typeStr='multi-point without cropping';
          else
            typeStr='single point with cropping';
          end
          %app.NetworktypeLabel_3.Text=['Network type: ',typeStr];
          
          if app.mainApp.deepLearningNetworks{sel(1)}{2}.trained==false
            app.TrainingstatusTextArea_3.Value="untrained";
          else
            tString={};
            tString{1}='Trained:';
            tString{2}=sprintf('Final training RMSE: %.2f',app.mainApp.deepLearningNetworks{sel(1)}{2}.trainingRMSE);
            tString{3}=sprintf('Final training loss: %.2f',app.mainApp.deepLearningNetworks{sel(1)}{2}.trainingLoss);
            app.TrainingstatusTextArea_3.Value=tString;
          end
          
        end
        
      end
      
    end
    
    function updateMovieinfo(app)
      % First tab
      if strcmpi(app.TabGroup.SelectedTab.Title,'Apply to a single movie (2D)')
        sel=app.SelectthetargetvideoDropDown.Value;
        
        app.EndframeEditField.Value=app.mainApp.FrameNumberSlider.Limits(2);
        app.EndframeEditField.Limits=[2 app.mainApp.FrameNumberSlider.Limits(2)];
        h=app.mainApp.handles{300+sel};
        vname=getappdata(h,'fname');
        if isstr(vname) % simple string
          videoFile=vname;
        else
          videoFile=[vname.Path,filesep,vname.Name];
        end
        % Second tap
      elseif strcmpi(app.TabGroup.SelectedTab.Title,'Apply to multiple movies (3D)')
        sel=app.SelectthetargetvideoDropDown_2.Value;
        app.EndframeEditField_2.Value=app.mainApp.FrameNumberSlider.Limits(2);
        app.EndframeEditField_2.Limits=[2 app.mainApp.FrameNumberSlider.Limits(2)];
        
        % Third tab
      elseif strcmpi(app.TabGroup.SelectedTab.Title,'Refine a point')
        sel=app.SelectthetargetvideoDropDown_3.Value;
        app.EndframeEditField_3.Value=app.mainApp.FrameNumberSlider.Limits(2);
        app.EndframeEditField_3.Limits=[2 app.mainApp.FrameNumberSlider.Limits(2)];
      end
      
      
    end
    
    function [xyz,rmse] = getDLT(app,xy,fr,vnum,sp)
      
      % get xy points from the other cameras
      udist=sp2full(app.mainApp.xypts(fr,(1:2*app.mainApp.nvid)+(sp-1)*2*app.mainApp.nvid));
      
      % add in xypts from the detection
      udist(vnum*2-1:vnum*2)=xy;
      
      % undistort
      for i=1:numel(udist)/2
        if isempty(app.mainApp.camud{i})==false
          udist(1,i*2-1:i*2)=applyTform(app.mainApp.camud{i},udist(1,i*2-1:i*2));
        end
      end
      
      % get 3D value & residual
      [xyz,rmse]=dlt_reconstruct_v2(app.mainApp.dltcoef,udist); % do DLT
      xyz=full2sp(xyz);
      rmse=full2sp(rmse);
      
    end
    
    function results = updateVideoToNetDisplay(app)
      displayArray={};
      if isempty(app.movieToNet)==false
        for i=1:size(app.movieToNet,1)
          displayArray{i,1}=sprintf('Movie %.0d :: %s',app.movieToNet(i,1),app.mainApp.deepLearningNetworks{app.movieToNet(i,2)}{2}.name);
        end
      else
        displayArray{1}='';
      end
      app.videonetworkprocessinglistTextArea.Value=displayArray;
    end
    
    function results = updateRefineQueueDisplay(app)
      displayArray={};
      if isempty(app.refineQueue)==false
        for i=1:size(app.refineQueue,1)
          %[netNumber,vnum,sp,minQ,startFrame,endFrame,overWriteData,respectDLTthreshold];
          displayArray{i,1}=sprintf('%.0f| Net %.0d :: Movie %.0d :: Point %.0d :: frames %.0d-%.0d :: minQ %.3f :: overwrite %.0f :: DLT %.0f',...
            i,app.refineQueue(i,1),app.refineQueue(i,2),app.refineQueue(i,3),app.refineQueue(i,5),...
            app.refineQueue(i,6),app.refineQueue(i,4),app.refineQueue(i,7),app.refineQueue(i,8));
        end
        app.RunQueueButton.Enable=true;
        app.ClearQueueButton.Enable=true;
      else
        displayArray{1}='';
        app.RunQueueButton.Enable=false;
        app.ClearQueueButton.Enable=false;
      end
      app.ProcessingqueueTextArea.Value=displayArray;
    end
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, mainApp)
      app.mainApp = mainApp;
      
      % move
      mainAppPosition = app.mainApp.DLTdv8appUIFigure.Position;
      app.NetworkapplicationinterfaceUIFigure.Position(1:2)=mainAppPosition(1:2)+[10,30];
      
      TabGroupSelectionChanged(app) % populate the first tab
    end

    % Button pushed function: ExitButton
    function ExitButtonPushed(app, event)
      delete(app)
    end

    % Value changed function: SelectanetworktoapplyDropDown
    function SelectanetworktoapplyDropDownValueChanged(app, event)
      value = app.SelectanetworktoapplyDropDown.Value;
      updateNetinfo(app);
    end

    % Selection change function: TabGroup
    function TabGroupSelectionChanged(app, event)
      selectedTab = app.TabGroup.SelectedTab;
      
      % get IDs of type 1 (autocrop) and 2 (whole movie) networks
      netNames={};
      netNumbers=[];
      cnt=1;
      for i=1:numel(app.mainApp.deepLearningNetworks)
        if app.mainApp.deepLearningNetworks{i}{2}.type < 3
          netNames{cnt}=app.mainApp.deepLearningNetworks{i}{2}.name;
          netNumbers(cnt)=i;
          cnt=cnt+1;
        end
      end
      
      % movie drop-down
      movieList={};
      for i=1:app.mainApp.nvid
        movieList{i}=['Movie ',num2str(i)];
      end
      
      % First tab
      if strcmpi(selectedTab.Title,'Apply to a single movie (2D)')
        
        app.SelectanetworktoapplyDropDown.Items=netNames;
        app.SelectanetworktoapplyDropDown.ItemsData=netNumbers;
        
        app.SelectthetargetvideoDropDown.Items=movieList;
        app.SelectthetargetvideoDropDown.ItemsData=1:numel(movieList);
        
        
        % second tab
      elseif strcmpi(selectedTab.Title,'Apply to multiple movies (3D)')
        
        app.SelectthetargetvideoDropDown_2.Items=movieList;
        app.SelectthetargetvideoDropDown_2.ItemsData=1:numel(movieList);
        
        app.SelectanetworktoapplyDropDown_2.Items=netNames;
        app.SelectanetworktoapplyDropDown_2.ItemsData=netNumbers;
        
        % third tab
      elseif strcmpi(selectedTab.Title,'Refine points')
        app.SelectthetargetvideoDropDown_3.Items=movieList;
        app.SelectthetargetvideoDropDown_3.ItemsData=1:numel(movieList);
        
        % get list of type 1 (autocropping) or type 3 (single point)
        % networks & set the "select a network to apply" menu
        netNames={};
        netNumbers=[];
        cnt=1;
        for i=1:numel(app.mainApp.deepLearningNetworks)
          if app.mainApp.deepLearningNetworks{i}{2}.type == 3 | app.mainApp.deepLearningNetworks{i}{2}.type == 1
            netNames{cnt}=app.mainApp.deepLearningNetworks{i}{2}.name;
            netNumbers(cnt)=i;
            cnt=cnt+1;
          end
        end
        app.SelectanetworktoapplyDropDown_3.Items=netNames;
        app.SelectanetworktoapplyDropDown_3.ItemsData=netNumbers;
        
        % select the target point menu
        pointNames={};
        for i=1:app.mainApp.numpts
          pointNames{i}=['Point ',num2str(i)];
        end
        pointNumbers=1:app.mainApp.numpts;
        app.SelectthetargetpointDropDown_2.Items=pointNames;
        app.SelectthetargetpointDropDown_2.ItemsData=pointNumbers;
        
        % if a cropping network is selected, disable the select target
        % point menu
        if app.mainApp.deepLearningNetworks{app.SelectanetworktoapplyDropDown_3.Value}{2}.type == 2
            app.SelectthetargetpointDropDown_2.Enable = false;
        else
            app.SelectthetargetpointDropDown_2.Enable = true;
        end
        
        
      end
      
      if isempty(netNames)==false
        updateNetinfo(app)
      end
      
      if isempty(movieList)==false
        updateMovieinfo(app)
      end
      
    end

    % Callback function
    function SelectanetworkDropDownValueChanged(app, event)
      value = app.SelectanetworkDropDown.Value;
      app.DescriptionTextArea_3.Value=app.importableNetworks{value}{2}.description;
      app.NumberofpointsEditField.Value=app.importableNetworks{value}{2}.numPoints;
      if app.importableNetworks{value}{2}.type==1
        typeStr='multi-point with cropping';
      elseif app.importableNetworks{value}{2}.type==2
        typeStr='multi-point without cropping';
      else
        typeStr='single point with cropping';
      end
      app.NetworktypeLabel.Text=['Network type: ',typeStr];
      
      if app.importableNetworks{value}{2}.trained==false
        app.TrainingstatusTextArea_2.Value="untrained";
      else
        disp('foo - add capabilities here')
      end
      
    end

    % Callback function
    function SelectanetworktoapplyDropDown_2ValueChanged(app, event)
      %value = app.SelectanetworktoapplyDropDown_2.Value;
      updateNetinfo(app);
    end

    % Value changed function: SelectthetargetvideoDropDown
    function SelectthetargetvideoDropDownValueChanged(app, event)
      %value = app.SelectthetargetvideoDropDown.Value;
      updateMovieinfo(app)
    end

    % Button pushed function: ApplynetworkButton
    function ApplynetworkButtonPushed(app, event)
      
      % setup UI controls
      app.ApplynetworkButton.Enable=false;
      app.StopButton.Enable=true;
      app.StopButton.Value=0;
      
      % get the network
      net=app.mainApp.deepLearningNetworks{app.SelectanetworktoapplyDropDown.Value}{1};
      netInfo=app.mainApp.deepLearningNetworks{app.SelectanetworktoapplyDropDown.Value}{2};
      netType=netInfo.type;
      numPts=netInfo.numPoints;
      
      % check the number of points defined in the project
      if app.mainApp.numpts<numPts
        disp('The network defines more points than are setup in the DLTdv8 project. Exiting network application.')
        app.ApplynetworkButton.Enable=true;
        app.StopButton.Enable=false;
        return
      end
      
      % get the movie fname & settings
      vnum=app.SelectthetargetvideoDropDown.Value;
      fname=getappdata(app.mainApp.handles{300+vnum},'fname');
      offset=str2double(get(app.mainApp.handles{325+vnum},'String'));
      frameRateRatio=app.mainApp.movFrameRateRatios(vnum);
      
      % get settings
      minQ=app.MinimummatchqualityEditField.Value;
      overWrite=app.OverwriteexistingdataCheckBox.Value;
      
      % setup DLTdv8a
      app.mainApp.lastvnum=vnum;
      app.mainApp.FrameNumberSlider.Value=app.StartframeEditField.Value;
      app.mainApp.storeUndo();
      
      % setup Network visualization window
      if app.VisualizenetworkoutputCheckBox.Value==true
          vfh=figure;
          afh=axes(vfh);
          imagesc(afh,rand(224,224));
          set(afh,'xtick',[],'ytick',[]);
          colorbar(afh);
          caxis(afh,[-0.1 1]);
      end
      
      % start of the per-frame evaluation loop
      for fr=app.StartframeEditField.Value:app.EndframeEditField.Value
        if app.StopButton.Value==1
          app.StopButton.Value=0;
          app.StopButton.Enable=false;
          app.ApplynetworkButton.Enable=true;
          return
        end
        
        % read the video image
        mov=mediaRead2(fname,round(fr*frameRateRatio+offset));
        img=single(mov.cdata)/255;
        
        % save in app image buffers
        app.mainApp.cdataCache{vnum}=pushBuffer(app.mainApp.cdataCache{vnum},mov.cdata);
        app.mainApp.cdataCacheIdx{vnum}=pushBuffer(app.mainApp.cdataCacheIdx{vnum},round(fr*frameRateRatio+offset));
        
        % apply network and get dnn points
        [xy,quality,rawNetOut]=netApply({img},net,{netInfo},netType,numPts,minQ);
        
        % update visualization
        if app.VisualizenetworkoutputCheckBox.Value==true
          try % try-catch block to work around user deleting the figure
            afh.Children(1).CData=rawNetOut{end};
            title(afh,sprintf('Frame %.0d raw network output',fr));
          catch
          end
          
        end

        % do 3D processing
        for j=1:size(xy,2)/2
          if app.mainApp.dlt==true && xy(j*2)~=0
            [xyz,rmse]=getDLT(app,xy(j*2-1:j*2),fr,vnum,j);
            if (rmse > app.mainApp.DLTresidualthresholdEditField.Value) && app.RespectDLTresidualthresholdCheckBox.Value==true
              dltOK=false;
            else
              dltOK=true;
            end
          else
            dltOK=true;
            xyz=[0,0,0];
            rmse=0;
          end
          
          % put the data back in the main app
          if (dltOK && overWrite) || (dltOK && app.mainApp.xypts(fr,vnum*2-1+(j-1)*2*app.mainApp.nvid)==0)
            app.mainApp.xypts(fr,vnum*2-1+(j-1)*2*app.mainApp.nvid)=xy(j*2-1);
            app.mainApp.xypts(fr,vnum*2+(j-1)*2*app.mainApp.nvid)=xy(j*2);
            % update 3D points if appropriate
            if isfinite(xyz(1)) && dltOK==true  && xyz(1)~=0
              app.mainApp.dltpts(fr,j*3-2:j*3)=full2sp(xyz);
              app.mainApp.dltres(fr,j)=full2sp(rmse);
            end
          end
        end
        
        
        % get a screen redraw
        app.mainApp.FrameNumberSlider.Value=fr;
        app.mainApp.fullRedraw(app.mainApp);
        drawnow
      end
      
      
      % reset the UI
      app.ApplynetworkButton.Enable=true;
      app.StopButton.Enable=false;
      
      % close the visualization figure
      try
          close(vfh);
      catch
      end
    end

    % Button pushed function: ClearprocessinglistButton
    function ClearprocessinglistButtonPushed(app, event)
      app.movieToNet=[];
      updateVideoToNetDisplay(app);
    end

    % Button pushed function: AddtoprocessinglistButton
    function AddtoprocessinglistButtonPushed(app, event)
      if isempty(app.movieToNet)==true || sum(app.movieToNet(:,1)==app.SelectthetargetvideoDropDown_2.Value)==0
        app.movieToNet(end+1,1:2)=[app.SelectthetargetvideoDropDown_2.Value,app.SelectanetworktoapplyDropDown_2.Value];
        app.movieToNet=sortrows(app.movieToNet,1);
        updateVideoToNetDisplay(app);
      else
        beep
      end
    end

    % Value changed function: SelectanetworktoapplyDropDown_2
    function SelectanetworktoapplyDropDown_2ValueChanged2(app, event)
      %value = app.SelectanetworktoapplyDropDown_2.Value;
      updateNetinfo(app);
    end

    % Button pushed function: ApplynetworksButton
    function ApplynetworksButtonPushed(app, event)
      % setup UI controls
      %app.ApplynetworksButton.Enable=false;
      app.StopButton_2.Enable=true;
      app.StopButton_2.Value=0;
      
      % get the networks
      for i=1:size(app.movieToNet,1)
        net(i)=app.mainApp.deepLearningNetworks{app.movieToNet(i,2)}{1};
        netInfo{i}=app.mainApp.deepLearningNetworks{app.movieToNet(i,2)}{2};
        netType(i)=netInfo{i}.type;
        numPts(i)=netInfo{i}.numPoints;
      end
      
      % get the movie fname & settings
      vnums=(app.movieToNet(:,1))';
      for i=1:numel(vnums)
        fnames{i}=getappdata(app.mainApp.handles{300+vnums(i)},'fname');
        offsets(i)=str2double(get(app.mainApp.handles{325+vnums(i)},'String'));
        frameRateRatios(i)=app.mainApp.movFrameRateRatios(vnums(i));
      end
      
      % get settings
      minQ=app.MinimummatchqualityEditField_2.Value;
      
      % setup DLTdv8a
      app.mainApp.UpdateallvideosCheckBox.Value=1;
      app.mainApp.FrameNumberSlider.Value=app.StartframeEditField_2.Value;
      app.mainApp.storeUndo();
      
      % start of the per-frame evaluation loop
      for fr=app.StartframeEditField_2.Value:app.EndframeEditField_2.Value
        
        % look for stop sign
        if app.StopButton_2.Value==1
          app.StopButton_2.Value=0;
          app.StopButton_2.Enable=false;
          app.ApplynetworksButton.Enable=true;
          return
        end
        
        % read videos
        for vnum=vnums
          % read the video image
          mov=mediaRead2(fnames{vnum},round(fr*frameRateRatios(vnum)+offsets(vnum)));
          img{vnum}=single(mov.cdata)/255;
          
          % save in app image buffers
          app.mainApp.cdataCache{vnum}=pushBuffer(app.mainApp.cdataCache{vnum},mov.cdata);
          app.mainApp.cdataCacheIdx{vnum}=pushBuffer(app.mainApp.cdataCacheIdx{vnum},round(fr*frameRateRatios(vnum)+offsets(vnum)));
        end
        
        % apply network and get dnn points
        [xypts,quality]=netApply(img,net,netInfo,netType,numPts,minQ);
        
        % get existing data for points
        ePts=app.mainApp.xypts(fr,1:app.mainApp.nvid*2*max(numPts));
        
        % fill dnn points into existing points
        nPts=ePts;
        sourceQuality=zeros(1,numel(nPts)/2); % array for the source and quality of the detections
        sourceQuality(nPts(1:2:end)~=0)=100; % existing non-dnn points get high quality
        for i=1:size(xypts,1) % for each video
          for pt=1:size(xypts,2)/2
            if app.OverwriteexistingdataCheckBox_2.Value==true || ePts( (pt-1)*app.mainApp.nvid*2 + vnums(i)*2-1)==0
              nPts( (pt-1)*app.mainApp.nvid*2 + (vnums(i)*2-1:vnums(i)*2))=xypts(i,pt*2-1:pt*2); % camera vnum(i), point pt
              sourceQuality( (pt-1)*app.mainApp.nvid + vnums(i) ) = quality(i,pt); % fill in dnn quality
            end
          end
        end
        nPts=sp2full(nPts);
        
        % do 3D processing
        if app.mainApp.dlt==true
          for pt=1:max(numPts) % for each point
            udist=nPts((1:2*app.mainApp.nvid) + (pt-1)*2*app.mainApp.nvid);
            uQual=sourceQuality(1:app.mainApp.nvid + (pt-1)*app.mainApp.nvid);
            
            % undistort
            for i=1:numel(udist)/2
              if isempty(app.mainApp.camud{i})==false
                udist(1,i*2-1:i*2)=applyTform(app.mainApp.camud{i},udist(1,i*2-1:i*2));
              end
            end
            
            % get 3D value & residual
            [xyz,rmse]=dlt_reconstruct_v2(app.mainApp.dltcoef,udist);
            xyz=full2sp(xyz);
            rmse=full2sp(rmse);
            
            if (rmse > app.mainApp.DLTresidualthresholdEditField.Value) && app.RespectDLTresidualthresholdCheckBox_2.Value==true
              dltOK=false;
              % update the main app only if overwrite data is set,
              % otherwise leave existing data in place
              if app.OverwriteexistingdataCheckBox_2.Value==true
                app.mainApp.xypts(fr,(1:2*app.mainApp.nvid + (pt-1)*2*app.mainApp.nvid))=app.mainApp.xypts(fr,(1:2*app.mainApp.nvid + (pt-1)*2*app.mainApp.nvid))*0;
                app.mainApp.dltpts(fr,pt*3-2:pt*3)=xyz*0;
                app.mainApp.dltres(fr,pt)=rmse*0;
              end
            else
              dltOK=true;
              app.mainApp.xypts(fr,(1:2*app.mainApp.nvid + (pt-1)*2*app.mainApp.nvid))=full2sp(nPts(1:2*app.mainApp.nvid + (pt-1)*2*app.mainApp.nvid));
              app.mainApp.dltpts(fr,pt*3-2:pt*3)=xyz;
              app.mainApp.dltres(fr,pt)=rmse;
            end
            
          end % end of for each point
        end % end of if dlt
        
        % get a screen redraw
        app.mainApp.FrameNumberSlider.Value=fr;
        app.mainApp.fullRedraw(app.mainApp);
        drawnow
        
      end % end of for each frame
      
      % reset the UI
      app.ApplynetworksButton.Enable=true;
      app.StopButton_2.Enable=false;
      
    end

    % Value changed function: StopButton_2
    function StopButton_2ValueChanged(app, event)
      value = app.StopButton_2.Value;
      
    end

    % Button pushed function: RunQueueButton
    function RunQueueButtonPushed(app, event)
      % setup UI controls
      app.RunQueueButton.Enable=false;
      app.StopButton_3.Enable=true;
      app.StopButton_3.Value=0;
      
      % get the queue
      Q=app.refineQueue;
      
      % store for Undo
      app.mainApp.storeUndo();
      
      for q=1:size(Q,1)
        %[1-netNumber,2-vnum,3-sp,4-minQ,5-startFrame,6-endFrame,7-overWriteData,8-respectDLTthreshold,9-DLTthreshold];
        
        % get the network
        net=app.mainApp.deepLearningNetworks{Q(q,1)}{1};
        netInfo=app.mainApp.deepLearningNetworks{Q(q,1)}{2};
        netType=netInfo.type;
        numPts=netInfo.numPoints;
        blockSize = 112;
        
        % get the movie fname & settings
        %vnum=app.SelectthetargetvideoDropDown_3.Value;
        vnum=Q(q,2);
        fname=getappdata(app.mainApp.handles{300+vnum},'fname');
        offset=str2double(get(app.mainApp.handles{325+vnum},'String'));
        frameRateRatio=app.mainApp.movFrameRateRatios(vnum);
        %sp=app.SelectthetargetpointDropDown_2.Value; % selected point to track
        sp=Q(q,3); % point to track
        app.mainApp.sp=sp;
        
        % get settings
        %minQ=app.MinimummatchqualityEditField_3.Value;
        minQ=Q(q,4);
        dltThreshold=Q(q,9);
        
        % setup DLTdv8a
        app.mainApp.lastvnum=vnum;
        app.mainApp.FrameNumberSlider.Value=app.StartframeEditField.Value;
        app.mainApp.CurrentpointDropDown.Value=num2str(sp);
        
        % start of the per-frame evaluation loop
        for fr=Q(q,5):Q(q,6)
            
          % check for a stop button press
          if app.StopButton_3.Value==1
            app.StopButton_3.Value=0;
            app.StopButton_3.Enable=false;
            app.RunQueueButton.Enable=true;
            return
          end
          
          % get the current point location
          if netType==3
            pts=sp2full(app.mainApp.xypts(:,(vnum*2-1:vnum*2)+(sp-1)*2*app.mainApp.nvid));
            idx=find(isfinite(pts(:,1)));
            if idx(1)>fr % jump to the start of the data if necessary
              fr=idx(1);
            end
          else
            % all points for cropping-net data
            pts=sp2full(app.mainApp.xypts(fr,sort([vnum*2-1+(0:netInfo.numPoints-1)*2*app.mainApp.nvid,vnum*2+...
                (0:netInfo.numPoints-1)*2*app.mainApp.nvid])));
          end
          
          % exit conditions for single-point nets: check overwrite and exit this loop iteration if it's defined and we're not over-writing
          if netType == 3 && isnan(pts(fr,1))==false && Q(q,7)==false
            continue
          end
          
          % exit conditions for multi-point nets: can't do nothin' with no
          % data
          if netType == 1 && sum(isfinite(pts))==0
            continue
          end
          
          % read the video image
          mov=mediaRead2(fname,round(fr*frameRateRatio+offset));
          imdat=single(mov.cdata)/255;
          
          % save in app image buffers
          app.mainApp.cdataCache{vnum}=pushBuffer(app.mainApp.cdataCache{vnum},mov.cdata);
          app.mainApp.cdataCacheIdx{vnum}=pushBuffer(app.mainApp.cdataCacheIdx{vnum},round(fr*frameRateRatio+offset));
          
          % determine where to look
          if netType==3 % single point network
            if isnan(pts(fr,1)) % no location, so get a guess if possible
              [x,y]=AutoTrackPredictor(pts,fr-1,2); % 1 = Kalman, 2 = linear, 3 = static
            else
              x=pts(fr,1);
              y=pts(fr,2);
            end
            
            blockSize2=blockSize; % set cropping blocksize
          else % multi-point crop net
            bb=[nanmin(pts(1:2:end)),nanmin(pts(2:2:end))]; % corner and extent bounding box
            bb(3:4)=[nanmax(pts(1:2:end)),nanmax(pts(2:2:end))]-bb(1:2);
            
            x=bb(1)+bb(3)/2;
            y=bb(2)+bb(4)/2;
            
            blockSize2=round(max([round(max(bb(3:4))*1.15),min(netInfo.minCropSize)])/2);
            blockSize2=max([blockSize2,112]); % don't let the cropping size drop below 1:1 to the native network size
            
          end
          if isnan(x)
            continue % go to the next loop iteration if we have nothing to look at
          end
          
          % get crop region
          rx=[-blockSize2 blockSize2-1]+round(x(1)); % basic
          % check borders
          if rx(1)<1
            rx=rx-rx(1)+1;
          end
          if rx(2)>size(imdat,2)
            rx=rx-rx(2)+size(imdat,2);
          end
          ry=[-blockSize2 blockSize2-1]+round(y(1)); % basic
          if ry(1)<1
            ry=ry-ry(1)+1;
          end
          if ry(2)>size(imdat,1)
            ry=ry-ry(2)+size(imdat,1);
          end
          
          % pull the subregion
          img2=imdat(ry(1):ry(2),rx(1):rx(2),:);
          if size(img2,3)==1
            img2=repmat(img2,[1 1 3]);
          end
          
          % resize
          if size(img2,1:2)==[blockSize*2,blockSize*2]
            img2r=img2;
          else
            img2r=imresize(img2,[blockSize*2,blockSize*2]);
          end
          
          % get a network prediction & find the best match
          imOut=predict(net,img2r);
          
          % setup the selected point data
          if netType==3
            sp=sp;
          else
            sp=1:netInfo.numPoints;
          end
          
          for i=1:size(imOut,3)-1
            [m,n]=find(imOut(:,:,i)==max(max(imOut(:,:,i))));
            
            % try for a weighted average
            try
              v=imOut(m-2:m+2,n-2:n+2,i); % net output, centered on xy
              [X,Y]=meshgrid(-2:2,-2:2);
              n(1)=n(1)+mean(mean(v.*X));
              m(1)=m(1)+mean(mean(v.*Y));
            catch
              % do nothing
            end
            
            cp=[rx(1)+n(1)/blockSize*blockSize2,ry(1)+m(1)/blockSize*blockSize2];
            
            fitt=max(max(imOut(:,:,i)));
            
            % write into app.mainApp.xypts if pass thresholds & overwrite is
            % set
            if fitt>minQ && (app.mainApp.xypts(fr,vnum*2+(sp(i)-1)*2*app.mainApp.nvid)==0 || Q(q,7)==true)
              app.mainApp.xypts(fr,(vnum*2-1:vnum*2)+(sp(i)-1)*2*app.mainApp.nvid)=full2sp(cp);
            elseif fitt<minQ && Q(q,7)==true % write a zero if overwrite is set and we did not pass threshold
              app.mainApp.xypts(fr,(vnum*2-1:vnum*2)+(sp(i)-1)*2*app.mainApp.nvid)=[0,0];
            else
              continue % can't overwrite (and therefore won't update DLT), go to next point
            end
            
            % check if we should do DLT
            if app.mainApp.dlt
              
              if sum(app.mainApp.xypts(fr,(1:app.mainApp.nvid*2)+(sp(i)-1)*2*app.mainApp.nvid)~=0)>=4 && ~isempty(app.mainApp.dltcoef) % 2+ xypts & DLT coefficients
                udist=sp2full(app.mainApp.xypts(fr,(1:2*app.mainApp.nvid)+(sp(i)-1)*2*app.mainApp.nvid));
                for j=1:numel(udist)./2
                  if isempty(app.mainApp.camud{vnum})==false
                    udist(1,j*2-1:j*2)=applyTform(app.mainApp.camud{vnum},udist(1,j*2-1:j*2));
                  end
                end
                [xyz,res]=dlt_reconstruct_v2(app.mainApp.dltcoef,udist); % do DLT
                
                if res > dltThreshold && (Q(q,8) == true)
                  app.mainApp.xypts(fr,(vnum*2-1:vnum*2)+(sp(i)-1)*2*app.mainApp.nvid)=full2sp([0,0]); % back out the xypts that were set earlier
                  app.mainApp.dltpts(fr,sp(i)*3-2:sp(i)*3)=full2sp([0,0,0]); % set null DLT points
                  app.mainApp.dltres(fr,sp(i))=full2sp(0); % set null residual
                else
                  app.mainApp.dltpts(fr,sp(i)*3-2:sp(i)*3)=full2sp(xyz(1:3)); % get the DLT points
                  app.mainApp.dltres(fr,sp(i))=full2sp(res); % get the DLT residual
                end
              else
                app.mainApp.dltpts(fr,sp(i)*3-2:sp(i)*3)=0; % set DLT points to 0
                app.mainApp.dltres(fr,sp(i))=0; % set DLT residuals to 0
              end
            end
            
          end
          
          % advance a frame and redraw the screen
          app.mainApp.FrameNumberSlider.Value=fr;
          fullRedraw(app.mainApp);
          drawnow
          %disp(['Finished frame ',num2str(fr)])
          
        end
        % remove top entry from the queue
        try
            app.refineQueue(1,:)=[];
            updateRefineQueueDisplay(app);
        catch ME
            % nothing
        end
        
      end
      
      % reset the UI
      app.RunQueueButton.Enable=true;
      app.StopButton_3.Enable=false;
    end

    % Value changed function: SelectanetworktoapplyDropDown_3
    function SelectanetworktoapplyDropDown_3ValueChanged(app, event)
      %value = app.SelectanetworktoapplyDropDown_3.Value;
      % if a cropping network is selected, disable the select target
      % point menu
      if app.mainApp.deepLearningNetworks{app.SelectanetworktoapplyDropDown_3.Value}{2}.type == 1
        app.SelectthetargetpointDropDown_2.Enable = false;
      else
        app.SelectthetargetpointDropDown_2.Enable = true;
      end
      
      % update network description in the tab
      updateNetinfo(app)
    end

    % Button pushed function: AddtoQueueButton
    function AddtoQueueButtonPushed(app, event)
      % gather & validate all info from the UI
      % get the network
      netNumber=app.SelectanetworktoapplyDropDown_3.Value;
      
      % get the movie fname & settings
      vnum=app.SelectthetargetvideoDropDown_3.Value;
      sp=app.SelectthetargetpointDropDown_2.Value; % selected point to track
      
      % get settings
      minQ=app.MinimummatchqualityEditField_3.Value;
      startFrame=app.StartframeEditField_3.Value;
      endFrame=app.EndframeEditField_3.Value;
      overWriteData=app.OverwriteexistingdataCheckBox_3.Value;
      respectDLTthreshold=app.RespectDLTresidualthresholdCheckBox_3.Value;
      
      % get mainApp setting
      dltThreshold=app.mainApp.DLTresidualthresholdEditField.Value;
      
      app.refineQueue(end+1,:)=[netNumber,vnum,sp,minQ,startFrame,endFrame,overWriteData,respectDLTthreshold,dltThreshold];
      
      % update queue display
      updateRefineQueueDisplay(app)
    end

    % Button pushed function: ClearQueueButton
    function ClearQueueButtonPushed(app, event)
      app.refineQueue=[];
      updateRefineQueueDisplay(app);
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.NetworkapplicationinterfaceUIFigure = uifigure('Visible', 'off');
      app.NetworkapplicationinterfaceUIFigure.Name = 'Network application interface';
      app.TabGroup = uitabgroup(app.NetworkapplicationinterfaceUIFigure);
      app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
      app.Applytoasinglemovie2DTab = uitab(app.TabGroup);
      app.Applytoasinglemovie2DTab.Tooltip = {''};
      app.Applytoasinglemovie2DTab.Title = 'Apply to a single movie (2D)';
      app.SelectanetworktoapplyDropDownLabel = uilabel(app.Applytoasinglemovie2DTab);
      app.SelectanetworktoapplyDropDownLabel.Text = 'Select a network to apply:';
      app.SelectanetworktoapplyDropDown = uidropdown(app.Applytoasinglemovie2DTab);
      app.SelectanetworktoapplyDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectanetworktoapplyDropDownValueChanged, true);
      app.SelectanetworktoapplyDropDown.Tooltip = {'The network used for the analysis'};
      app.DescriptionTextArea_2Label = uilabel(app.Applytoasinglemovie2DTab);
      app.DescriptionTextArea_2Label.Text = 'Description:';
      app.DescriptionTextArea_2 = uitextarea(app.Applytoasinglemovie2DTab);
      app.DescriptionTextArea_2.Editable = 'off';
      app.NumberofpointsLabel = uilabel(app.Applytoasinglemovie2DTab);
      app.NumberofpointsLabel.Text = 'Number of points:';
      app.NetworktypeLabel_2 = uilabel(app.Applytoasinglemovie2DTab);
      app.NetworktypeLabel_2.Text = 'Network type:';
      app.TrainingstatusLabel_4 = uilabel(app.Applytoasinglemovie2DTab);
      app.TrainingstatusLabel_4.Text = 'Training status:';
      app.TrainingstatusTextArea = uitextarea(app.Applytoasinglemovie2DTab);
      app.TrainingstatusTextArea.Editable = 'off';
      app.SelectthetargetvideoDropDownLabel = uilabel(app.Applytoasinglemovie2DTab);
      app.SelectthetargetvideoDropDownLabel.Text = 'Select the target video';
      app.SelectthetargetvideoDropDown = uidropdown(app.Applytoasinglemovie2DTab);
      app.SelectthetargetvideoDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectthetargetvideoDropDownValueChanged, true);
      app.SelectthetargetvideoDropDown.Tooltip = {'The video that the network will be applied to'};
      app.StartframeEditFieldLabel = uilabel(app.Applytoasinglemovie2DTab);
      app.StartframeEditFieldLabel.Text = 'Start frame';
      app.StartframeEditField = uieditfield(app.Applytoasinglemovie2DTab, 'numeric');
      app.StartframeEditField.Limits = [1 Inf];
      app.StartframeEditField.RoundFractionalValues = 'on';
      app.StartframeEditField.ValueDisplayFormat = '%11.0f';
      app.StartframeEditField.Tooltip = {'First video frame to analyze'};
      app.StartframeEditField.Value = 1;
      app.EndframeEditFieldLabel = uilabel(app.Applytoasinglemovie2DTab);
      app.EndframeEditFieldLabel.Text = 'End frame';
      app.EndframeEditField = uieditfield(app.Applytoasinglemovie2DTab, 'numeric');
      app.EndframeEditField.Limits = [1 Inf];
      app.EndframeEditField.RoundFractionalValues = 'on';
      app.EndframeEditField.ValueDisplayFormat = '%11.0f';
      app.EndframeEditField.Tooltip = {'Last video frame to analyze'};
      app.EndframeEditField.Value = 1;
      app.ApplynetworkButton = uibutton(app.Applytoasinglemovie2DTab, 'push');
      app.ApplynetworkButton.ButtonPushedFcn = createCallbackFcn(app, @ApplynetworkButtonPushed, true);
      app.ApplynetworkButton.Tooltip = {'Apply the network as per the settings above'};
      app.ApplynetworkButton.Text = 'Apply network';
      app.MinimummatchqualityEditFieldLabel = uilabel(app.Applytoasinglemovie2DTab);
      app.MinimummatchqualityEditFieldLabel.Text = 'Minimum match quality';
      app.MinimummatchqualityEditField = uieditfield(app.Applytoasinglemovie2DTab, 'numeric');
      app.MinimummatchqualityEditField.Limits = [0 Inf];
      app.MinimummatchqualityEditField.Tooltip = {'Minumum quality (range 0 - 1) required to accept the network output as valid'};
      app.MinimummatchqualityEditField.Value = 0.5;
      app.OverwriteexistingdataCheckBox = uicheckbox(app.Applytoasinglemovie2DTab);
      app.OverwriteexistingdataCheckBox.Text = 'Overwrite existing data';
      app.OverwriteexistingdataCheckBox.Value = true;
      app.StopButton = uibutton(app.Applytoasinglemovie2DTab, 'state');
      app.StopButton.Enable = 'off';
      app.StopButton.Tooltip = {'Stop network application in progress'};
      app.StopButton.Text = 'Stop!';
      app.RespectDLTresidualthresholdCheckBox = uicheckbox(app.Applytoasinglemovie2DTab);
      app.RespectDLTresidualthresholdCheckBox.Text = 'Respect DLT residual threshold';
      app.VisualizenetworkoutputCheckBox = uicheckbox(app.Applytoasinglemovie2DTab);
      app.VisualizenetworkoutputCheckBox.Tooltip = {'Show the raw network output in a separate window.'};
      app.VisualizenetworkoutputCheckBox.Text = 'Visualize network output';
      app.Applytomultiplemovies3DTab = uitab(app.TabGroup);
      app.Applytomultiplemovies3DTab.Title = 'Apply to multiple movies (3D)';
      app.NumberofpointsLabel_2 = uilabel(app.Applytomultiplemovies3DTab);
      app.NumberofpointsLabel_2.Text = 'Number of points:';
      app.NetworktypeLabel_3 = uilabel(app.Applytomultiplemovies3DTab);
      app.NetworktypeLabel_3.Text = 'Network type:';
      app.SelectanetworktoapplyDropDown_2Label = uilabel(app.Applytomultiplemovies3DTab);
      app.SelectanetworktoapplyDropDown_2Label.Text = 'Select a network to apply:';
      app.SelectanetworktoapplyDropDown_2 = uidropdown(app.Applytomultiplemovies3DTab);
      app.SelectanetworktoapplyDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SelectanetworktoapplyDropDown_2ValueChanged2, true);
      app.SelectanetworktoapplyDropDown_2.Tooltip = {'The network used for the analysis'};
      app.DescriptionTextArea_3Label = uilabel(app.Applytomultiplemovies3DTab);
      app.DescriptionTextArea_3Label.Text = 'Description:';
      app.DescriptionTextArea_3 = uitextarea(app.Applytomultiplemovies3DTab);
      app.DescriptionTextArea_3.Editable = 'off';
      app.TrainingstatusTextArea_2Label = uilabel(app.Applytomultiplemovies3DTab);
      app.TrainingstatusTextArea_2Label.Text = 'Training status:';
      app.TrainingstatusTextArea_2 = uitextarea(app.Applytomultiplemovies3DTab);
      app.TrainingstatusTextArea_2.Editable = 'off';
      app.SelectthetargetvideoDropDown_2Label = uilabel(app.Applytomultiplemovies3DTab);
      app.SelectthetargetvideoDropDown_2Label.Text = 'Select the target video';
      app.SelectthetargetvideoDropDown_2 = uidropdown(app.Applytomultiplemovies3DTab);
      app.SelectthetargetvideoDropDown_2.Tooltip = {'The video that the network will be applied to'};
      app.OverwriteexistingdataCheckBox_2 = uicheckbox(app.Applytomultiplemovies3DTab);
      app.OverwriteexistingdataCheckBox_2.Text = 'Overwrite existing data';
      app.OverwriteexistingdataCheckBox_2.Value = true;
      app.RespectDLTresidualthresholdCheckBox_2 = uicheckbox(app.Applytomultiplemovies3DTab);
      app.RespectDLTresidualthresholdCheckBox_2.Text = 'Respect DLT residual threshold';
      app.ApplynetworksButton = uibutton(app.Applytomultiplemovies3DTab, 'push');
      app.ApplynetworksButton.ButtonPushedFcn = createCallbackFcn(app, @ApplynetworksButtonPushed, true);
      app.ApplynetworksButton.Tooltip = {'Apply the network as per the settings above'};
      app.ApplynetworksButton.Text = 'Apply networks';
      app.StopButton_2 = uibutton(app.Applytomultiplemovies3DTab, 'state');
      app.StopButton_2.ValueChangedFcn = createCallbackFcn(app, @StopButton_2ValueChanged, true);
      app.StopButton_2.Enable = 'off';
      app.StopButton_2.Tooltip = {'Stop network application in progress'};
      app.StopButton_2.Text = 'Stop!';
      app.StartframeEditField_2Label = uilabel(app.Applytomultiplemovies3DTab);
      app.StartframeEditField_2Label.Text = 'Start frame';
      app.StartframeEditField_2 = uieditfield(app.Applytomultiplemovies3DTab, 'numeric');
      app.StartframeEditField_2.Limits = [1 Inf];
      app.StartframeEditField_2.RoundFractionalValues = 'on';
      app.StartframeEditField_2.ValueDisplayFormat = '%11.0f';
      app.StartframeEditField_2.Tooltip = {'First video frame to analyze'};
      app.StartframeEditField_2.Value = 1;
      app.EndframeEditField_2Label = uilabel(app.Applytomultiplemovies3DTab);
      app.EndframeEditField_2Label.Text = 'End frame';
      app.EndframeEditField_2 = uieditfield(app.Applytomultiplemovies3DTab, 'numeric');
      app.EndframeEditField_2.Limits = [1 Inf];
      app.EndframeEditField_2.RoundFractionalValues = 'on';
      app.EndframeEditField_2.ValueDisplayFormat = '%11.0f';
      app.EndframeEditField_2.Tooltip = {'Last video frame to analyze'};
      app.EndframeEditField_2.Value = 1;
      app.MinimummatchqualityEditField_2Label = uilabel(app.Applytomultiplemovies3DTab);
      app.MinimummatchqualityEditField_2Label.Text = 'Minimum match quality';
      app.MinimummatchqualityEditField_2 = uieditfield(app.Applytomultiplemovies3DTab, 'numeric');
      app.MinimummatchqualityEditField_2.Limits = [0 Inf];
      app.MinimummatchqualityEditField_2.Tooltip = {'Minumum quality (range 0 - 1) required to accept the network output as valid'};
      app.MinimummatchqualityEditField_2.Value = 0.5;
      app.videonetworkprocessinglistTextAreaLabel = uilabel(app.Applytomultiplemovies3DTab);
      app.videonetworkprocessinglistTextAreaLabel.Text = 'video :: network processing list';
      app.videonetworkprocessinglistTextArea = uitextarea(app.Applytomultiplemovies3DTab);
      app.videonetworkprocessinglistTextArea.Editable = 'off';
      app.videonetworkprocessinglistTextArea.Tooltip = {'Shows what movie and network pairs will be processed'};
      app.AddtoprocessinglistButton = uibutton(app.Applytomultiplemovies3DTab, 'push');
      app.AddtoprocessinglistButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoprocessinglistButtonPushed, true);
      app.AddtoprocessinglistButton.Tooltip = {'Apply the network as per the settings above'};
      app.AddtoprocessinglistButton.Text = 'Add to processing list';
      app.ClearprocessinglistButton = uibutton(app.Applytomultiplemovies3DTab, 'push');
      app.ClearprocessinglistButton.ButtonPushedFcn = createCallbackFcn(app, @ClearprocessinglistButtonPushed, true);
      app.ClearprocessinglistButton.Text = 'Clear processing list';
      app.RefinepointsTab = uitab(app.TabGroup);
      app.RefinepointsTab.Title = 'Refine points';
      app.NumberofpointsLabel_3 = uilabel(app.RefinepointsTab);
      app.NumberofpointsLabel_3.Text = 'Number of points:';
      app.RunQueueButton = uibutton(app.RefinepointsTab, 'push');
      app.RunQueueButton.ButtonPushedFcn = createCallbackFcn(app, @RunQueueButtonPushed, true);
      app.RunQueueButton.Enable = 'off';
      app.RunQueueButton.Tooltip = {'Run the processing queue in order'};
      app.RunQueueButton.Text = 'Run Queue!';
      app.OverwriteexistingdataCheckBox_3 = uicheckbox(app.RefinepointsTab);
      app.OverwriteexistingdataCheckBox_3.Text = 'Overwrite existing data';
      app.OverwriteexistingdataCheckBox_3.Value = true;
      app.StopButton_3 = uibutton(app.RefinepointsTab, 'state');
      app.StopButton_3.Enable = 'off';
      app.StopButton_3.Tooltip = {'Stop network application in progress'};
      app.StopButton_3.Text = 'Stop!';
      app.RespectDLTresidualthresholdCheckBox_3 = uicheckbox(app.RefinepointsTab);
      app.RespectDLTresidualthresholdCheckBox_3.Text = 'Respect DLT residual threshold';
      app.SelectanetworktoapplyDropDown_3Label = uilabel(app.RefinepointsTab);
      app.SelectanetworktoapplyDropDown_3Label.Text = 'Select a network to apply:';
      app.SelectanetworktoapplyDropDown_3 = uidropdown(app.RefinepointsTab);
      app.SelectanetworktoapplyDropDown_3.ValueChangedFcn = createCallbackFcn(app, @SelectanetworktoapplyDropDown_3ValueChanged, true);
      app.SelectanetworktoapplyDropDown_3.Tooltip = {'The network used for the analysis'};
      app.DescriptionTextArea_4Label = uilabel(app.RefinepointsTab);
      app.DescriptionTextArea_4Label.Text = 'Description:';
      app.DescriptionTextArea_4 = uitextarea(app.RefinepointsTab);
      app.DescriptionTextArea_4.Editable = 'off';
      app.TrainingstatusTextArea_3Label = uilabel(app.RefinepointsTab);
      app.TrainingstatusTextArea_3Label.Text = 'Training status:';
      app.TrainingstatusTextArea_3 = uitextarea(app.RefinepointsTab);
      app.TrainingstatusTextArea_3.Editable = 'off';
      app.StartframeEditField_3Label = uilabel(app.RefinepointsTab);
      app.StartframeEditField_3Label.Text = 'Start frame';
      app.StartframeEditField_3 = uieditfield(app.RefinepointsTab, 'numeric');
      app.StartframeEditField_3.Limits = [1 Inf];
      app.StartframeEditField_3.RoundFractionalValues = 'on';
      app.StartframeEditField_3.ValueDisplayFormat = '%11.0f';
      app.StartframeEditField_3.Tooltip = {'First video frame to analyze'};
      app.StartframeEditField_3.Value = 1;
      app.EndframeEditField_3Label = uilabel(app.RefinepointsTab);
      app.EndframeEditField_3Label.Text = 'End frame';
      app.EndframeEditField_3 = uieditfield(app.RefinepointsTab, 'numeric');
      app.EndframeEditField_3.Limits = [1 Inf];
      app.EndframeEditField_3.RoundFractionalValues = 'on';
      app.EndframeEditField_3.ValueDisplayFormat = '%11.0f';
      app.EndframeEditField_3.Tooltip = {'Last video frame to analyze'};
      app.EndframeEditField_3.Value = 1;
      app.MinimummatchqualityEditField_3Label = uilabel(app.RefinepointsTab);
      app.MinimummatchqualityEditField_3Label.Text = 'Minimum match quality';
      app.MinimummatchqualityEditField_3 = uieditfield(app.RefinepointsTab, 'numeric');
      app.MinimummatchqualityEditField_3.Limits = [0 Inf];
      app.MinimummatchqualityEditField_3.Tooltip = {'Minumum quality (range 0 - 1) required to accept the network output as valid'};
      app.MinimummatchqualityEditField_3.Value = 0.5;
      app.SelectthetargetpointDropDown_2Label = uilabel(app.RefinepointsTab);
      app.SelectthetargetpointDropDown_2Label.Text = 'Select the target point';
      app.SelectthetargetpointDropDown_2 = uidropdown(app.RefinepointsTab);
      app.SelectthetargetpointDropDown_2.Tooltip = {'The video that the network will be applied to'};
      app.SelectthetargetvideoDropDown_3Label = uilabel(app.RefinepointsTab);
      app.SelectthetargetvideoDropDown_3Label.Text = 'Select the target video';
      app.SelectthetargetvideoDropDown_3 = uidropdown(app.RefinepointsTab);
      app.SelectthetargetvideoDropDown_3.Tooltip = {'The video that the network will be applied to'};
      app.AddtoQueueButton = uibutton(app.RefinepointsTab, 'push');
      app.AddtoQueueButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoQueueButtonPushed, true);
      app.AddtoQueueButton.Text = 'Add to Queue';
      app.ProcessingqueueLabel = uilabel(app.RefinepointsTab);
      app.ProcessingqueueLabel.Text = 'Processing queue:';
      app.ProcessingqueueTextArea = uitextarea(app.RefinepointsTab);
      app.ClearQueueButton = uibutton(app.RefinepointsTab, 'push');
      app.ClearQueueButton.ButtonPushedFcn = createCallbackFcn(app, @ClearQueueButtonPushed, true);
      app.ClearQueueButton.Enable = 'off';
      app.ClearQueueButton.Tooltip = {'Clear all entries from the processing queue'};
      app.ClearQueueButton.Text = 'Clear Queue';
      app.DLTdv8DeepLearningnetworkapplicationinterfaceLabel = uilabel(app.NetworkapplicationinterfaceUIFigure);
      app.DLTdv8DeepLearningnetworkapplicationinterfaceLabel.Text = 'DLTdv8 Deep Learning network application interface';
      app.ExitButton = uibutton(app.NetworkapplicationinterfaceUIFigure, 'push');
      app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
      app.ExitButton.Tooltip = {'Exit the network application interface'};
      app.ExitButton.Text = 'Exit';
      % Show the figure after all components are created
      app.NetworkapplicationinterfaceUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = NetworkApplicationInterface(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.NetworkapplicationinterfaceUIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.NetworkapplicationinterfaceUIFigure)
    end
  end
end