classdef NetworkManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NetworkmanagerUIFigure          matlab.ui.Figure
        ExitButton                      matlab.ui.control.Button
        DLTdv8DeepLearningnetworkcreationandmanagementinterfaceLabel  matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        CreateanetworkTab               matlab.ui.container.Tab
        NetworktypeButtonGroup          matlab.ui.container.ButtonGroup
        MultipointtrackerwithautocroppingButton  matlab.ui.control.RadioButton
        MultipointtrackerwithoutcroppingButton  matlab.ui.control.RadioButton
        SinglepointtrackerwithcroppingButton  matlab.ui.control.RadioButton
        MessagesLabel                   matlab.ui.control.Label
        NumberofpointstolearnEditField  matlab.ui.control.NumericEditField
        NumberofpointstolearnEditFieldLabel  matlab.ui.control.Label
        DescriptionTextArea             matlab.ui.control.TextArea
        DescriptionTextAreaLabel        matlab.ui.control.Label
        NetworknameEditField            matlab.ui.control.EditField
        NetworknameEditFieldLabel       matlab.ui.control.Label
        CreatenetworkButton             matlab.ui.control.Button
        ImportanetworkTab               matlab.ui.container.Tab
        TrainingstatusTextArea_2        matlab.ui.control.TextArea
        TrainingstatusLabel_5           matlab.ui.control.Label
        NetworktypeLabel                matlab.ui.control.Label
        MessagesTextArea                matlab.ui.control.TextArea
        MessagesTextAreaLabel           matlab.ui.control.Label
        ImportButton                    matlab.ui.control.Button
        NumberofpointsEditField         matlab.ui.control.NumericEditField
        NumberofpointsEditFieldLabel    matlab.ui.control.Label
        DescriptionTextArea_3           matlab.ui.control.TextArea
        DescriptionTextArea_3Label      matlab.ui.control.Label
        SelectanetworkDropDown          matlab.ui.control.DropDown
        SelectanetworkDropDownLabel     matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        SelectafiletoimportfromButton   matlab.ui.control.Button
        ManagenetworksTab               matlab.ui.container.Tab
        RenamenetworkButton             matlab.ui.control.Button
        TrainingstatusTextArea          matlab.ui.control.TextArea
        TrainingstatusLabel_4           matlab.ui.control.Label
        NetworktypeLabel_2              matlab.ui.control.Label
        ExportnetworkButton             matlab.ui.control.Button
        DeletenetworkButton             matlab.ui.control.Button
        NumberofpointsLabel             matlab.ui.control.Label
        DescriptionTextArea_2           matlab.ui.control.TextArea
        DescriptionTextArea_2Label      matlab.ui.control.Label
        SelectanetworktomanageDropDown  matlab.ui.control.DropDown
        SelectanetworktomanageDropDownLabel  matlab.ui.control.Label
        TrainanetworkTab                matlab.ui.container.Tab
        HaltTrainingButton              matlab.ui.control.StateButton
        UsewhichpointfortrainingEditField  matlab.ui.control.NumericEditField
        UsewhichpointfortrainingEditFieldLabel  matlab.ui.control.Label
        SelectdatastoresfortrainingListBox  matlab.ui.control.ListBox
        SelectdatastoresfortrainingListBoxLabel  matlab.ui.control.Label
        MinibatchsizeEditField          matlab.ui.control.NumericEditField
        MinibatchsizeEditFieldLabel     matlab.ui.control.Label
        InitiallearningrateEditField    matlab.ui.control.NumericEditField
        InitiallearningrateEditFieldLabel  matlab.ui.control.Label
        MaximumofepochsEditField        matlab.ui.control.NumericEditField
        MaximumofepochsEditFieldLabel   matlab.ui.control.Label
        TrainingstatusTextArea_4        matlab.ui.control.TextArea
        TrainingstatusTextArea_4Label   matlab.ui.control.Label
        StarttrainingButton             matlab.ui.control.Button
        TrainwithaugmenteddataCheckBox  matlab.ui.control.CheckBox
        DescriptionTextArea_4           matlab.ui.control.TextArea
        DescriptionTextArea_4Label      matlab.ui.control.Label
        NetworktypeLabel_3              matlab.ui.control.Label
        NumberofpointsLabel_2           matlab.ui.control.Label
        SelectanetworktotrainDropDown   matlab.ui.control.DropDown
        SelectanetworktotrainDropDownLabel  matlab.ui.control.Label
    end

  
  properties (Access = private)
    mainApp % mainApp object
    importableDatastores % cell array of dataStores that could be imported
    importableNetworks % cell array of networks that could be imported
  end
  
  methods (Access = private)
    
    function [] = updateNetinfo(app)
      % update the interface in the various tabs
      
      % Third tab
      if strcmpi(app.TabGroup.SelectedTab.Title,'Manage networks')
        sel=app.SelectanetworktomanageDropDown.Value;
        if isempty(sel)==false
          app.DescriptionTextArea_2.Value=app.mainApp.deepLearningNetworks{sel(1)}{2}.description;
          app.NumberofpointsLabel.Text=['Number of points: ',num2str(app.mainApp.deepLearningNetworks{sel(1)}{2}.numPoints)];
          
          % type: 1 = multi-point with cropping, 2 = multi-point no cropping,
          % 3 = single point cropping
          if app.mainApp.deepLearningNetworks{sel(1)}{2}.type==1
            typeStr='multi-point with cropping';
          elseif app.mainApp.deepLearningNetworks{sel(1)}{2}.type==2
            typeStr='multi-point without cropping';
          else
            typeStr='single point with cropping';
          end
          
          app.NetworktypeLabel_2.Text=['Network type: ',typeStr];
          if app.mainApp.deepLearningNetworks{sel(1)}{2}.trained==false
            app.TrainingstatusTextArea.Value="untrained";
          else
            tString={};
            tString{1}='Trained:';
            tString{2}=sprintf('Final training RMSE: %.2f',app.mainApp.deepLearningNetworks{sel(1)}{2}.trainingRMSE);
            tString{3}=sprintf('Final training loss: %.2f',app.mainApp.deepLearningNetworks{sel(1)}{2}.trainingLoss);
            app.TrainingstatusTextArea.Value=tString;
          end
          app.DeletenetworkButton.Enable=true;
          app.ExportnetworkButton.Enable=true;
          app.RenamenetworkButton.Enable=true;
          
        else
          app.DescriptionTextArea_2.Value='';
          app.NumberofpointsLabel.Text='Number of points:';
          app.NetworktypeLabel_2.Text='Network type: ';
          app.TrainingstatusTextArea.Value='';
          app.DeletenetworkButton.Enable=false;
          app.ExportnetworkButton.Enable=false;
          
        end
      % tab 4
      elseif strcmpi(app.TabGroup.SelectedTab.Title,'Train a network')
          sel=app.SelectanetworktotrainDropDown.Value;
          if isempty(sel)==false
            app.NumberofpointsLabel_2.Text=['Number of points: ',num2str(app.mainApp.deepLearningNetworks{sel(1)}{2}.numPoints)];
            app.DescriptionTextArea_4.Value=app.mainApp.deepLearningNetworks{sel(1)}{2}.description;
            
            
            % type: 1 = multi-point with cropping, 2 = multi-point no cropping,
            % 3 = single point cropping
            if app.mainApp.deepLearningNetworks{sel(1)}{2}.type==1
              typeStr='multi-point with cropping';
              app.UsewhichpointfortrainingEditField.Enable=false;
              app.UsewhichpointfortrainingEditFieldLabel.Enable=false;
            elseif app.mainApp.deepLearningNetworks{sel(1)}{2}.type==2
              typeStr='multi-point without cropping';
              app.UsewhichpointfortrainingEditField.Enable=false;
              app.UsewhichpointfortrainingEditFieldLabel.Enable=false;
            else
              typeStr='single point with cropping';
              app.UsewhichpointfortrainingEditField.Enable=true;
              app.UsewhichpointfortrainingEditFieldLabel.Enable=true;
            end
            app.NetworktypeLabel_3.Text=['Network type: ',typeStr];
            
            if app.mainApp.deepLearningNetworks{sel(1)}{2}.trained==false
              app.TrainingstatusTextArea_4.Value="untrained";
            else
                tString={};
                tString{1}='Trained:';
                tString{2}=sprintf('Final training RMSE: %.2f',app.mainApp.deepLearningNetworks{sel(1)}{2}.trainingRMSE);
                tString{3}=sprintf('Final training loss: %.2f',app.mainApp.deepLearningNetworks{sel(1)}{2}.trainingLoss);
                app.TrainingstatusTextArea_4.Value=tString;
            end
            
            % update datastores list
            dsNames={};
            dsItems=[];
            cnt=1;
            for i=1:numel(app.mainApp.dataStores)
                np=app.mainApp.dataStores{i}.numPoints;
%                 if app.mainApp.deepLearningNetworks{sel(1)}{2}.type==3 && np~=1
%                     continue
%                 end
              dsNames{cnt}=app.mainApp.dataStores{i}.name;
              dsItems(cnt)=i;
              cnt=cnt+1;
            end
            if isempty(dsItems)==false
                app.SelectdatastoresfortrainingListBox.Enable=true;
                app.SelectdatastoresfortrainingListBox.Items=dsNames;
                app.SelectdatastoresfortrainingListBox.ItemsData=dsItems;  
            else
                app.SelectdatastoresfortrainingListBox.Enable=false;
                app.SelectdatastoresfortrainingListBox.Items={''};
            end
            
          end
          
      end
      
    end
  end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
      app.mainApp = mainApp;
     
      % make radio buttons easy to parse
      for i=1:3
          app.NetworktypeButtonGroup.Children(i).UserData=i;
      end
      
      % move
      mainAppPosition = app.mainApp.DLTdv8appUIFigure.Position;
      app.NetworkmanagerUIFigure.Position(1:2)=mainAppPosition(1:2)+[10,30];
      
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
      delete(app)
        end

        % Button pushed function: CreatenetworkButton
        function CreatenetworkButtonPushed(app, event)
      % validate MATLAB
      if exist('nnet.cnn.layer.PixelClassificationLayer','class')~=8
          app.MessagesLabel.Text=['You need the Deep Learning and Computer Vision Toolboxes ',...
              'to use Deep Learning features in DLTdv8a.  Consider trying the packaged versions ',...
              'that use the MATLAB runtime.'];
          return
      end
            
            % validate setup
      a=app.DescriptionTextArea.Value;
      if isempty(a{1})
        app.MessagesLabel.Text='Networks must have a description';
        return
      end
      if isempty(app.NetworknameEditField.Value)
        app.MessagesLabel.Text='Networks must have a name';
        return
      end
      
      % setup network info bundle
      netInfo.name=app.NetworknameEditField.Value;
      netInfo.description=app.DescriptionTextArea.Value;
      netInfo.trained=false;
      netInfo.numPoints=app.NumberofpointstolearnEditField.Value;
      % type: 1 = multi-point with cropping, 2 = multi-point no cropping,
      % 3 = single point cropping
      netInfo.type = app.NetworktypeButtonGroup.SelectedObject.UserData;
      if netInfo.type == 3 && app.NumberofpointstolearnEditField.Value~=1
        app.MessagesLabel.Text='Single point tracker networks should have only 1 point to learn';
        return
      end
      
      % start creating a new network
      app.MessagesLabel.Text='Working ...';
      app.CreatenetworkButton.Enable=false;
      drawnow

      progressDialog = uiprogressdlg(app.NetworkmanagerUIFigure,'Indeterminate','on',...
                        'Message','Creating new network...',...
                        'Title','Please wait');
      
      numClasses = app.NumberofpointstolearnEditField.Value+1; % +1 is for the summed image
      
      % load base network
      load('baseNetwork.mat','lgraph');
      
      % new upSampler layer
      newB=zeros(1,1,numClasses);
      W=lgraph.Layers(97).Weights;
      for i=1:numClasses
        newW(:,:,i,i)=W(:,:,1,1);
      end
      upSampler3n = transposedConv2dLayer([8,8],numClasses,'NumChannels',numClasses,'name', ...
        'dec_upsample2','Stride',[4 4],'WeightLearnRateFactor',0,...
        'WeightL2Factor',1,'BiasInitializer','zeros','WeightsInitializer',...
        'glorot','BiasL2Factor',0,'BiasLearnRateFactor',0,'Bias',newB,...
        'Cropping',2,'Weights',newW);
      
      % new scorer layer with the correct number of outputs
      scorer3n = convolution2dLayer([1 1],numClasses,'name','scorer','WeightLearnRateFactor',10,...
        'WeightL2Factor',1,'BiasLearnRateFactor',0,'BiasL2Factor',0);
      
      % copy the n-layer versions into the base network
      lgraph = replaceLayer(lgraph,lgraph.Layers(96).Name,scorer3n);
      lgraph = replaceLayer(lgraph,lgraph.Layers(97).Name,upSampler3n);
      
      % setup for image to image regression
      lgraph = lgraph.removeLayers('softmax-out');
      lgraph = lgraph.removeLayers('classification');
      lgraph = lgraph.addLayers(regressionLayer('name','regressionLayer'));
      lgraph = lgraph.connectLayers('dec_crop2','regressionLayer');
      
      % store the network
      app.mainApp.deepLearningNetworks{end+1}={lgraph,netInfo};
     app.MessagesLabel.Text='Network created';
     app.DescriptionTextArea.Value='';
     app.NetworknameEditField.Value='';
     
     app.CreatenetworkButton.Enable=true;
     
     % update the tracker menu in DLTdv8a
      app.mainApp.updateTrackerMenu();

      delete(progressDialog);
      

        end

        % Value changed function: SelectanetworktomanageDropDown
        function SelectanetworktomanageDropDownValueChanged(app, event)
      value = app.SelectanetworktomanageDropDown.Value;
      updateNetinfo(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
      selectedTab = app.TabGroup.SelectedTab;
      
      netNames={};
      for i=1:numel(app.mainApp.deepLearningNetworks)
        netNames{i}=app.mainApp.deepLearningNetworks{i}{2}.name;
      end
      
      % Third tab
      if strcmpi(selectedTab.Title,'Manage networks')
        
        app.SelectanetworktomanageDropDown.Items=netNames;
        app.SelectanetworktomanageDropDown.ItemsData=1:numel(netNames);
        
        if isempty(netNames)==false
          updateNetinfo(app)
        else
          app.DeletenetworkButton.Enable=false;
          app.ExportnetworkButton.Enable=false;
          app.RenamenetworkButton.Enable=false;
          
        end
      elseif strcmpi(selectedTab.Title,'Train a network')
          app.SelectanetworktotrainDropDown.Items=netNames;
          app.SelectanetworktotrainDropDown.ItemsData=1:numel(netNames);
          
          updateNetinfo(app)
          
          
      end
      
        end

        % Button pushed function: DeletenetworkButton
        function DeletenetworkButtonPushed(app, event)
      sel=app.SelectanetworktomanageDropDown.Value;
      
      % get confirmation for trained networks
      if app.mainApp.deepLearningNetworks{sel}{2}.trained
        [button] = questdlg(['This is a trained network, are you sure you want to delete it?'],'Really?','Delete','Cancel','Cancel');
        if strcmpi(button,'cancel')
            return
        end
      end
      
      app.mainApp.deepLearningNetworks(sel(1))=[];
      TabGroupSelectionChanged(app,event)
      updateNetinfo(app);
        end

        % Button pushed function: ExportnetworkButton
        function ExportnetworkButtonPushed(app, event)
      sel=app.SelectanetworktomanageDropDown.Value;
      
      udExport.deepLearningNetworks{1}=app.mainApp.deepLearningNetworks{sel(1)};
      [filename, pathname] = uiputfile('*.mat', 'Save network as');
      save([pathname,filesep,filename],"udExport",'-v7.3');

      
        end

        % Callback function
        function RemovecachedimagesButtonPushed(app, event)
      sel=app.SelectanetworktomanageDropDown.Value;
      app.mainApp.dataStores{sel(1)}.clearCache();
      updateNetinfo(app);
        end

        % Callback function
        function CacheimagesButtonPushed(app, event)
      sel=app.SelectanetworktomanageDropDown.Value;
      app.mainApp.dataStores{sel(1)}.buildCache();
      updateNetinfo(app);
        end

        % Button pushed function: SelectafiletoimportfromButton
        function SelectafiletoimportfromButtonPushed(app, event)

      [file,path] = uigetfile('*.mat','Select a matlab file containing a DLTdv8 network');
      if file~=0
          mf=load([path,filesep,file]);
          app.MessagesTextArea.Value={'Loaded a mat-file'};
      else
          app.MessagesTextArea.Value={'File selection canceled'};
          return
      end
      try
          app.importableNetworks=mf.udExport.deepLearningNetworks;
          app.TextArea.Value=[path,filesep,file];
          app.SelectanetworkDropDown.Enable=true;
          app.SelectanetworkDropDownLabel.Enable=true;
          dsList={};
          for i=1:numel(app.importableNetworks)
              dsList{i}=app.importableNetworks{i}{2}.name;
          end
          app.ImportButton.Enable=true;
          app.SelectanetworkDropDown.Items=dsList;
          app.SelectanetworkDropDown.ItemsData=1:numel(dsList);
          app.SelectanetworkDropDown.Value=1;
          SelectanetworkDropDownValueChanged(app, event);

      catch
          app.TextArea.Value='';
          app.ImportButton.Enable=false;
          app.SelectanetworkDropDown.Items={};
          app.SelectanetworkDropDown.Enable=false;
          app.DescriptionTextArea_3.Value='';
          app.NumberofpointsEditField.Value=0;
          app.SourcemoviesTextArea_2.Value='';
          app.MessagesTextArea.Value={'No datastores found!'};
          
      end
      
        end

        % Value changed function: SelectanetworkDropDown
        function SelectanetworkDropDownValueChanged(app, event)
      value = app.SelectanetworkDropDown.Value;
      app.DescriptionTextArea_3.Value=app.importableNetworks{value}{2}.description;
      app.NumberofpointsEditField.Value=app.importableNetworks{value}{2}.numPoints;
      if app.importableNetworks{value}{2}.type==1
            typeStr='multi-point with cropping';
        elseif app.importableNetworks{value}{2}.type==2
            typeStr='multi-point without cropping';
        else
            typeStr='single point with cropping';
      end
      app.NetworktypeLabel.Text=['Network type: ',typeStr];
      
      if app.importableNetworks{value}{2}.trained==false
        app.TrainingstatusTextArea_2.Value="untrained";
      else
        tString={};
        tString{1}='Trained:';
        tString{2}=sprintf('Final training RMSE: %.2f',app.importableNetworks{value}{2}.trainingRMSE);
        tString{3}=sprintf('Final training loss: %.2f',app.importableNetworks{value}{2}.trainingLoss);
        app.TrainingstatusTextArea_2.Value=tString;
      end
      
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
        app.mainApp.deepLearningNetworks{end+1}=app.importableNetworks{app.SelectanetworkDropDown.Value};
        
        % update the tracker menu in DLTdv8a
        app.mainApp.updateTrackerMenu();
        app.MessagesTextArea.Value="Network imported!";
        end

        % Value changed function: SelectanetworktotrainDropDown
        function SelectanetworktotrainDropDownValueChanged(app, event)
      %value = app.SelectanetworktotrainDropDown.Value;
      updateNetinfo(app);
        end

        % Button pushed function: StarttrainingButton
        function StarttrainingButtonPushed(app, event)
      
      % get network
      net = app.mainApp.deepLearningNetworks{app.SelectanetworktotrainDropDown.Value}{1};
      netInfo = app.mainApp.deepLearningNetworks{app.SelectanetworktotrainDropDown.Value}{2};
        
      % get interface values
      maxEpochs = app.MaximumofepochsEditField.Value;
      initLearningRate = app.InitiallearningrateEditField.Value;
      miniBatchSize = app.MinibatchsizeEditField.Value;
      
      % configure the datastore(s)
      for i=1:numel(app.SelectdatastoresfortrainingListBox.Value)
        ds=app.mainApp.dataStores{app.SelectdatastoresfortrainingListBox.Value(i)};
        if netInfo.type==3
          ds.crop=true;
          if ds.numPoints>1
            ds.activePoints=app.UsewhichpointfortrainingEditField.Value;
            if app.UsewhichpointfortrainingEditField.Value==0
                ds.activePoints=1:ds.numPoints;
                ds.groupPoints=true;
            end
          end
        elseif netInfo.type==1 
          ds.cropAndResize=true;
          ds.activePoints=1:ds.numPoints;
        elseif netInfo.type==2 % whole-image network
          ds.crop=false;
          ds.activePoints=1:ds.numPoints;
          if ds.numPoints>1 & netInfo.numPoints==1
              ds.groupPoints=true;
          end
        else
          ds.activePoints=1:ds.numPoints;
        end
        ds.AugmentData=app.TrainwithaugmenteddataCheckBox.Value;
      end
      
      % bundle datastores
      if numel(app.SelectdatastoresfortrainingListBox.Value)>1
          ds=superMovieDatastore(app.mainApp.dataStores(app.SelectdatastoresfortrainingListBox.Value));
      end
      
      % set cropsize
      netInfo.minCropSize=ds.minCropSize;

      % full training options
      stopCheck=@(netState)netStopCheck(app,netState);
      options3 = trainingOptions('adam', ...
        'InitialLearnRate',initLearningRate, ...
        'LearnRateSchedule','piecewise', ...
        'LearnRateDropPeriod',10, ...
        'LearnRateDropFactor',0.1, ...
        'L2Regularization',0.0001, ...
        'MaxEpochs',maxEpochs ,...
        'MiniBatchSize',miniBatchSize, ...
        'GradientThresholdMethod','l2norm', ...
        'Plots','none', ...
        'ExecutionEnvironment','auto', ...
        'GradientThreshold',0.01,...
        'shuffle','never','OutputFcn',stopCheck);

      app.StarttrainingButton.Enable=false;
      app.HaltTrainingButton.Enable=true;
      drawnow
      
      % train the network
      ds.reset();
      if netInfo.trained==false
        [trainedNet, info] = trainNetwork(ds,net,options3);
      else
        [trainedNet, info] = trainNetwork(ds,layerGraph(net),options3);
      end
      
      app.mainApp.deepLearningNetworks{app.SelectanetworktotrainDropDown.Value}{1}=trainedNet;
      netInfo.trained=true;
      netInfo.trainingLoss=info.TrainingLoss(end);
      netInfo.trainingRMSE=info.TrainingRMSE(end);
      netInfo.lastLearningRate=info.BaseLearnRate(end);
      app.mainApp.deepLearningNetworks{app.SelectanetworktotrainDropDown.Value}{2}=netInfo;
        
      updateNetinfo(app)
      
      app.StarttrainingButton.Enable=true;
      app.HaltTrainingButton.Enable=false;
      app.HaltTrainingButton.Value=0;
        end

        % Value changed function: DescriptionTextArea_2
        function DescriptionTextArea_2ValueChanged(app, event)
      sel = app.SelectanetworktomanageDropDown.Value;
      value = app.DescriptionTextArea_2.Value;
      if isempty(value)
          value={''};
      end
      app.mainApp.deepLearningNetworks{sel}{2}.description=value;
      TabGroupSelectionChanged(app);
        end

        % Button pushed function: RenamenetworkButton
        function RenamenetworkButtonPushed(app, event)
      sel = app.SelectanetworktomanageDropDown.Value;
      newName=inputdlg('Edit the network name','Re-name network',1,{app.mainApp.deepLearningNetworks{sel}{2}.name});
      if isempty(newName)
          return
      end
      app.mainApp.deepLearningNetworks{sel}{2}.name=newName{1};
      TabGroupSelectionChanged(app);
        end

        % Value changed function: DescriptionTextArea_4
        function DescriptionTextArea_4ValueChanged(app, event)
      sel = app.SelectanetworktotrainDropDown.Value;
      value = app.DescriptionTextArea_4.Value;
      if isempty(value)
        value={''};
      end
      app.mainApp.deepLearningNetworks{sel}{2}.description=value;
      TabGroupSelectionChanged(app);
      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NetworkmanagerUIFigure = uifigure('Visible', 'off');
            app.NetworkmanagerUIFigure.Name = 'Network manager';
            app.TabGroup = uitabgroup(app.NetworkmanagerUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.CreateanetworkTab = uitab(app.TabGroup);
            app.CreateanetworkTab.Tooltip = {''};
            app.CreateanetworkTab.Title = 'Create a network';
            app.CreatenetworkButton = uibutton(app.CreateanetworkTab, 'push');
            app.CreatenetworkButton.ButtonPushedFcn = createCallbackFcn(app, @CreatenetworkButtonPushed, true);
            app.CreatenetworkButton.Text = 'Create network!';
            app.NetworknameEditFieldLabel = uilabel(app.CreateanetworkTab);
            app.NetworknameEditFieldLabel.Text = 'Network name:';
            app.NetworknameEditField = uieditfield(app.CreateanetworkTab, 'text');
            app.DescriptionTextAreaLabel = uilabel(app.CreateanetworkTab);
            app.DescriptionTextAreaLabel.Text = 'Description:';
            app.DescriptionTextArea = uitextarea(app.CreateanetworkTab);
            app.NumberofpointstolearnEditFieldLabel = uilabel(app.CreateanetworkTab);
            app.NumberofpointstolearnEditFieldLabel.Text = 'Number of points to learn:';
            app.NumberofpointstolearnEditField = uieditfield(app.CreateanetworkTab, 'numeric');
            app.NumberofpointstolearnEditField.Limits = [1 Inf];
            app.NumberofpointstolearnEditField.RoundFractionalValues = 'on';
            app.NumberofpointstolearnEditField.ValueDisplayFormat = '%11.0f';
            app.NumberofpointstolearnEditField.Value = 1;
            app.MessagesLabel = uilabel(app.CreateanetworkTab);
            app.MessagesLabel.Text = 'Messages:';
            app.NetworktypeButtonGroup = uibuttongroup(app.CreateanetworkTab);
            app.NetworktypeButtonGroup.Title = 'Network type';
            app.SinglepointtrackerwithcroppingButton = uiradiobutton(app.NetworktypeButtonGroup);
            app.SinglepointtrackerwithcroppingButton.Tooltip = {'For interactive tracking of a single point'};
            app.SinglepointtrackerwithcroppingButton.Text = 'Single point tracker with cropping';
            app.SinglepointtrackerwithcroppingButton.Value = true;
            app.MultipointtrackerwithoutcroppingButton = uiradiobutton(app.NetworktypeButtonGroup);
            app.MultipointtrackerwithoutcroppingButton.Tooltip = {'For tracking one or more points in one or more camera views. No cropping, so generally only good for images up to 1 megapixel size.'};
            app.MultipointtrackerwithoutcroppingButton.Text = 'Multi-point tracker without cropping';
            app.MultipointtrackerwithautocroppingButton = uiradiobutton(app.NetworktypeButtonGroup);
            app.MultipointtrackerwithautocroppingButton.Tooltip = {'For tracking one or more points in one or more camera views. Automatically crops the image to the tracked points. Best for large images.'};
            app.MultipointtrackerwithautocroppingButton.Text = 'Multi-point tracker with auto-cropping';
            app.ImportanetworkTab = uitab(app.TabGroup);
            app.ImportanetworkTab.Tooltip = {''};
            app.ImportanetworkTab.Title = 'Import a network';
            app.SelectafiletoimportfromButton = uibutton(app.ImportanetworkTab, 'push');
            app.SelectafiletoimportfromButton.ButtonPushedFcn = createCallbackFcn(app, @SelectafiletoimportfromButtonPushed, true);
            app.SelectafiletoimportfromButton.Tooltip = {'Select a saved DLTdv8 project file or exported network file.'};
            app.SelectafiletoimportfromButton.Text = 'Select a file to import from';
            app.TextArea = uitextarea(app.ImportanetworkTab);
            app.TextArea.Editable = 'off';
            app.SelectanetworkDropDownLabel = uilabel(app.ImportanetworkTab);
            app.SelectanetworkDropDownLabel.Enable = 'off';
            app.SelectanetworkDropDownLabel.Text = 'Select a network';
            app.SelectanetworkDropDown = uidropdown(app.ImportanetworkTab);
            app.SelectanetworkDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectanetworkDropDownValueChanged, true);
            app.SelectanetworkDropDown.Enable = 'off';
            app.DescriptionTextArea_3Label = uilabel(app.ImportanetworkTab);
            app.DescriptionTextArea_3Label.Text = 'Description';
            app.DescriptionTextArea_3 = uitextarea(app.ImportanetworkTab);
            app.DescriptionTextArea_3.Editable = 'off';
            app.NumberofpointsEditFieldLabel = uilabel(app.ImportanetworkTab);
            app.NumberofpointsEditFieldLabel.Text = 'Number of points';
            app.NumberofpointsEditField = uieditfield(app.ImportanetworkTab, 'numeric');
            app.NumberofpointsEditField.ValueDisplayFormat = '%11.0f';
            app.ImportButton = uibutton(app.ImportanetworkTab, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Enable = 'off';
            app.ImportButton.Text = 'Import!';
            app.MessagesTextAreaLabel = uilabel(app.ImportanetworkTab);
            app.MessagesTextAreaLabel.Text = 'Messages:';
            app.MessagesTextArea = uitextarea(app.ImportanetworkTab);
            app.MessagesTextArea.Editable = 'off';
            app.NetworktypeLabel = uilabel(app.ImportanetworkTab);
            app.NetworktypeLabel.Text = 'Network type:';
            app.TrainingstatusLabel_5 = uilabel(app.ImportanetworkTab);
            app.TrainingstatusLabel_5.Text = 'Training status:';
            app.TrainingstatusTextArea_2 = uitextarea(app.ImportanetworkTab);
            app.TrainingstatusTextArea_2.Editable = 'off';
            app.ManagenetworksTab = uitab(app.TabGroup);
            app.ManagenetworksTab.Tooltip = {''};
            app.ManagenetworksTab.Title = 'Manage networks';
            app.SelectanetworktomanageDropDownLabel = uilabel(app.ManagenetworksTab);
            app.SelectanetworktomanageDropDownLabel.Text = 'Select a network to manage:';
            app.SelectanetworktomanageDropDown = uidropdown(app.ManagenetworksTab);
            app.SelectanetworktomanageDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectanetworktomanageDropDownValueChanged, true);
            app.DescriptionTextArea_2Label = uilabel(app.ManagenetworksTab);
            app.DescriptionTextArea_2Label.Text = 'Description:';
            app.DescriptionTextArea_2 = uitextarea(app.ManagenetworksTab);
            app.DescriptionTextArea_2.ValueChangedFcn = createCallbackFcn(app, @DescriptionTextArea_2ValueChanged, true);
            app.DescriptionTextArea_2.Tooltip = {'Network description, edit by typing in a revision'};
            app.NumberofpointsLabel = uilabel(app.ManagenetworksTab);
            app.NumberofpointsLabel.Text = 'Number of points:';
            app.DeletenetworkButton = uibutton(app.ManagenetworksTab, 'push');
            app.DeletenetworkButton.ButtonPushedFcn = createCallbackFcn(app, @DeletenetworkButtonPushed, true);
            app.DeletenetworkButton.Enable = 'off';
            app.DeletenetworkButton.Text = 'Delete network';
            app.ExportnetworkButton = uibutton(app.ManagenetworksTab, 'push');
            app.ExportnetworkButton.ButtonPushedFcn = createCallbackFcn(app, @ExportnetworkButtonPushed, true);
            app.ExportnetworkButton.Enable = 'off';
            app.ExportnetworkButton.Tooltip = {'Export the network to a file for use in another DLTdv8 project.'};
            app.ExportnetworkButton.Text = 'Export network';
            app.NetworktypeLabel_2 = uilabel(app.ManagenetworksTab);
            app.NetworktypeLabel_2.Text = 'Network type:';
            app.TrainingstatusLabel_4 = uilabel(app.ManagenetworksTab);
            app.TrainingstatusLabel_4.Text = 'Training status:';
            app.TrainingstatusTextArea = uitextarea(app.ManagenetworksTab);
            app.TrainingstatusTextArea.Editable = 'off';
            app.RenamenetworkButton = uibutton(app.ManagenetworksTab, 'push');
            app.RenamenetworkButton.ButtonPushedFcn = createCallbackFcn(app, @RenamenetworkButtonPushed, true);
            app.RenamenetworkButton.Enable = 'off';
            app.RenamenetworkButton.Tooltip = {'click to re-name the selected network'};
            app.RenamenetworkButton.Text = 'Re-name network';
            app.TrainanetworkTab = uitab(app.TabGroup);
            app.TrainanetworkTab.Title = 'Train a network';
            app.SelectanetworktotrainDropDownLabel = uilabel(app.TrainanetworkTab);
            app.SelectanetworktotrainDropDownLabel.Text = 'Select a network to train:';
            app.SelectanetworktotrainDropDown = uidropdown(app.TrainanetworkTab);
            app.SelectanetworktotrainDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectanetworktotrainDropDownValueChanged, true);
            app.NumberofpointsLabel_2 = uilabel(app.TrainanetworkTab);
            app.NumberofpointsLabel_2.Text = 'Number of points:';
            app.NetworktypeLabel_3 = uilabel(app.TrainanetworkTab);
            app.NetworktypeLabel_3.Text = 'Network type:';
            app.DescriptionTextArea_4Label = uilabel(app.TrainanetworkTab);
            app.DescriptionTextArea_4Label.Text = 'Description:';
            app.DescriptionTextArea_4 = uitextarea(app.TrainanetworkTab);
            app.DescriptionTextArea_4.ValueChangedFcn = createCallbackFcn(app, @DescriptionTextArea_4ValueChanged, true);
            app.DescriptionTextArea_4.Tooltip = {'You can directly edit the network description.'};
            app.DescriptionTextArea_4.Value = {'tab4'};
            app.TrainwithaugmenteddataCheckBox = uicheckbox(app.TrainanetworkTab);
            app.TrainwithaugmenteddataCheckBox.Text = 'Train with augmented data';
            app.TrainwithaugmenteddataCheckBox.Value = true;
            app.StarttrainingButton = uibutton(app.TrainanetworkTab, 'push');
            app.StarttrainingButton.ButtonPushedFcn = createCallbackFcn(app, @StarttrainingButtonPushed, true);
            app.StarttrainingButton.Text = 'Start training!';
            app.TrainingstatusTextArea_4Label = uilabel(app.TrainanetworkTab);
            app.TrainingstatusTextArea_4Label.Text = 'Training status:';
            app.TrainingstatusTextArea_4 = uitextarea(app.TrainanetworkTab);
            app.TrainingstatusTextArea_4.Editable = 'off';
            app.TrainingstatusTextArea_4.Value = {'tab4'};
            app.MaximumofepochsEditFieldLabel = uilabel(app.TrainanetworkTab);
            app.MaximumofepochsEditFieldLabel.Text = 'Maximum # of epochs';
            app.MaximumofepochsEditField = uieditfield(app.TrainanetworkTab, 'numeric');
            app.MaximumofepochsEditField.Limits = [1 Inf];
            app.MaximumofepochsEditField.RoundFractionalValues = 'on';
            app.MaximumofepochsEditField.Tooltip = {'1 epoch = 1 full pass over the training dataset'};
            app.MaximumofepochsEditField.Value = 25;
            app.InitiallearningrateEditFieldLabel = uilabel(app.TrainanetworkTab);
            app.InitiallearningrateEditFieldLabel.Text = 'Initial learning rate';
            app.InitiallearningrateEditField = uieditfield(app.TrainanetworkTab, 'numeric');
            app.InitiallearningrateEditField.Limits = [0 Inf];
            app.InitiallearningrateEditField.ValueDisplayFormat = '%11.11g';
            app.InitiallearningrateEditField.Tooltip = {'If the learning rate is too low, then training takes a long time. If the learning rate is too high, then training might reach a suboptimal result or diverge.  The learning rate is reduced through time during training.'};
            app.InitiallearningrateEditField.Value = 0.0001;
            app.MinibatchsizeEditFieldLabel = uilabel(app.TrainanetworkTab);
            app.MinibatchsizeEditFieldLabel.Text = 'Minibatch size';
            app.MinibatchsizeEditField = uieditfield(app.TrainanetworkTab, 'numeric');
            app.MinibatchsizeEditField.Limits = [1 Inf];
            app.MinibatchsizeEditField.RoundFractionalValues = 'on';
            app.MinibatchsizeEditField.Tooltip = {'Number of datastore reads in each mini-batch.  Larger numbers = slower, more thorough training. A mini-batch is a subset of the training set that is used to evaluate the gradient of the loss function and update the weights.'};
            app.MinibatchsizeEditField.Value = 20;
            app.SelectdatastoresfortrainingListBoxLabel = uilabel(app.TrainanetworkTab);
            app.SelectdatastoresfortrainingListBoxLabel.Text = 'Select datastores for training:';
            app.SelectdatastoresfortrainingListBox = uilistbox(app.TrainanetworkTab);
            app.SelectdatastoresfortrainingListBox.Multiselect = 'on';
            app.SelectdatastoresfortrainingListBox.Enable = 'off';
            app.SelectdatastoresfortrainingListBox.Tooltip = {'Select one or more datastores to use for training the network'};
            app.SelectdatastoresfortrainingListBox.Value = {'Item 1'};
            app.UsewhichpointfortrainingEditFieldLabel = uilabel(app.TrainanetworkTab);
            app.UsewhichpointfortrainingEditFieldLabel.Enable = 'off';
            app.UsewhichpointfortrainingEditFieldLabel.Tooltip = {'If you''re training a single-point network from a multi-point datastores, use this setting to control which point is used.'};
            app.UsewhichpointfortrainingEditFieldLabel.Text = 'Use which point for training?';
            app.UsewhichpointfortrainingEditField = uieditfield(app.TrainanetworkTab, 'numeric');
            app.UsewhichpointfortrainingEditField.Limits = [0 Inf];
            app.UsewhichpointfortrainingEditField.RoundFractionalValues = 'on';
            app.UsewhichpointfortrainingEditField.Enable = 'off';
            app.UsewhichpointfortrainingEditField.Tooltip = {'If you''re training a single-point network from a multi-point datastores, use this setting to control which point is used. Enter 0 to train from ALL points in the datastore.'};
            app.UsewhichpointfortrainingEditField.Value = 1;
            app.HaltTrainingButton = uibutton(app.TrainanetworkTab, 'state');
            app.HaltTrainingButton.Enable = 'off';
            app.HaltTrainingButton.Tooltip = {'Click here to halt training at the current iteration.'};
            app.HaltTrainingButton.Text = 'Halt Training!';
            app.DLTdv8DeepLearningnetworkcreationandmanagementinterfaceLabel = uilabel(app.NetworkmanagerUIFigure);
            app.DLTdv8DeepLearningnetworkcreationandmanagementinterfaceLabel.Text = 'DLTdv8 Deep Learning network creation and management interface';
            app.ExitButton = uibutton(app.NetworkmanagerUIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Tooltip = {'Exit the datastore interface'};
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.NetworkmanagerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NetworkManager(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NetworkmanagerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NetworkmanagerUIFigure)
        end
    end
end