classdef pointMetaEditor < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    pointMetaEditorUIFigure     matlab.ui.Figure
    AddpointButton              matlab.ui.control.Button
    MergepointsButton           matlab.ui.control.Button
    SwappointsButton            matlab.ui.control.Button
    DeletepointButton           matlab.ui.control.Button
    ExitButton                  matlab.ui.control.Button
    DLTdv8pointmetaeditorLabel  matlab.ui.control.Label
    pointsTable                 matlab.ui.control.Table
  end

  
  properties (Access = private)
    mainApp % handle back to the main DLTdv8a app
  end
  
  methods (Access = public)
    
    function [] = drawPointTable(app)

      % populate the data table
      data=[];
      for i=1:app.mainApp.numpts
        data.Selected(i,1)=false;
        data.Name(i,1)=app.mainApp.pointNames(i);
      end
      app.pointsTable.Data=struct2table(data);
      app.pointsTable.ColumnEditable=[true,true];
      app.pointsTable.Data.Selected(app.mainApp.sp)=true;
      
    end

    
    function updateButtons(app)
      % disable all buttons first
      app.DeletepointButton.Enable=false;
      app.MergepointsButton.Enable=false;
      app.SwappointsButton.Enable=false;

      % enable delete if 1 selected and more than 1 point
      if sum(app.pointsTable.Data{:,1})==1 & size(app.pointsTable.Data,1)>1
        app.DeletepointButton.Enable=true;
      end

      % enable merge & swap if two selected
      if sum(app.pointsTable.Data{:,1})==2
        app.MergepointsButton.Enable=true;
        app.SwappointsButton.Enable=true;
      end
      
    end
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, mainApp)
      % store the handle to the mainApp for use by subfunctions of this app
      app.mainApp=mainApp;

      % store our handle in the main app
      %app.mainApp.pointEditorHandle{1}=app;

      % draw the points table
      drawPointTable(app)

      % update the buttons
      updateButtons(app)


    end

    % Cell edit callback: pointsTable
    function pointsTableCellEdit(app, event)
      indices = event.Indices;
      newData = event.NewData;

      if indices(2)==1 % if in the checkbox column
        updateButtons(app);
      end

      if indices(2)==2 % if in the names column
        % clean the string - allow only letters numbers, and _
        % max 64 characters
        tf=isstrprop(newData,'alphanum');
        tf(strfind(newData,'_'))=true; 

        % create new string
        ns=newData;
        ns(tf==false)='_'; % replace bad characters
        ns=ns(1:min([numel(ns),64])); % trucate
        % fix empty strings, let's not do that!
        if isempty(newData)
          ns=sprintf('pt%.0f',indices(1));
        end
        % push the modified string back to the table
        app.pointsTable.Data(indices(1),indices(2))={ns};

        % push it back to the main app data
        app.mainApp.pointNames(indices(1))={ns};

        % update the main interface menu strings
        ptstring={};
        for i=1:app.mainApp.numpts
          ptstring(i)=(app.pointsTable.Data{i,2});
        end
        app.mainApp.CurrentpointDropDown.Items=ptstring;
      end
      
    end

    % Button pushed function: ExitButton
    function ExitButtonPushed(app, event)
      %disp('Exit button pushed')
      try
        %app.mainApp.pointEditorHandle(1)=[];
        app.mainApp.subAppObjects{4}=[];
      catch
      end
      delete(app);
    end

    % Button pushed function: DeletepointButton
    function DeletepointButtonPushed(app, event)
      %disp('Delete button pushed')

      % store the undo
      storeUndo(app.mainApp)
      
      % delete the checked point
      dvDeletePoint(app.mainApp, find(app.pointsTable.Data{:,1}==true));

      % re-init the table
      drawPointTable(app);
      updateButtons(app);

      % update the main interface menu strings
      ptstring={};
      for i=1:app.mainApp.numpts
        ptstring(i)=(app.pointsTable.Data{i,2});
      end
      app.mainApp.CurrentpointDropDown.Items=ptstring;

      % redraw DLTdv8a main interface
      fullRedraw(app.mainApp);
      
    end

    % Button pushed function: SwappointsButton
    function SwappointsButtonPushed(app, event)
      %disp('Swap button pushed')

      % store the undo
      storeUndo(app.mainApp);

      % do the data operations
      idx=find(app.pointsTable.Data{:,1}==true);
      dvSwapPoints(app.mainApp,idx(1),idx(2),[1,size(app.mainApp.xypts,1)]);

      % re-init the table
      drawPointTable(app);
      updateButtons(app);

      % update the main interface menu strings
      ptstring={};
      for i=1:app.mainApp.numpts
        ptstring(i)=(app.pointsTable.Data{i,2});
      end
      app.mainApp.CurrentpointDropDown.Items=ptstring;

      % redraw DLTdv8a main interface
      fullRedraw(app.mainApp);

    end

    % Button pushed function: MergepointsButton
    function MergepointsButtonPushed(app, event)
      %disp('Merge button pushed')
      storeUndo(app.mainApp);
      idx=find(app.pointsTable.Data{:,1}==true);
      dvJoinPoints(app.mainApp,idx(1),idx(2));

      % re-init the table
      drawPointTable(app);
      updateButtons(app);

      % update the main interface menu strings
      ptstring={};
      for i=1:app.mainApp.numpts
        ptstring(i)=(app.pointsTable.Data{i,2});
      end
      app.mainApp.CurrentpointDropDown.Items=ptstring;

      % redraw DLTdv8a main interface
      fullRedraw(app.mainApp);

    end

    % Close request function: pointMetaEditorUIFigure
    function pointMetaEditorUIFigureCloseRequest(app, event)
      % unregister with the main app
      try
        %app.mainApp.pointEditorHandle(1)=[];
        app.mainApp.subAppObjects{4}=[];
      catch
      end
      delete(app)
    end

    % Button pushed function: AddpointButton
    function AddpointButtonPushed(app, event)
      addPoint(app.mainApp)
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.pointMetaEditorUIFigure = uifigure('Visible', 'off');
      app.pointMetaEditorUIFigure.Name = 'MATLAB App';
      app.pointMetaEditorUIFigure.CloseRequestFcn = createCallbackFcn(app, @pointMetaEditorUIFigureCloseRequest, true);
      app.pointsTable = uitable(app.pointMetaEditorUIFigure);
      app.pointsTable.ColumnName = {'Selected'; 'Name'};
      app.pointsTable.RowName = {};
      app.pointsTable.CellEditCallback = createCallbackFcn(app, @pointsTableCellEdit, true);
      app.DLTdv8pointmetaeditorLabel = uilabel(app.pointMetaEditorUIFigure);
      app.DLTdv8pointmetaeditorLabel.Text = 'DLTdv8 point meta editor';
      app.ExitButton = uibutton(app.pointMetaEditorUIFigure, 'push');
      app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
      app.ExitButton.Tooltip = {'Exit the point editor interface'};
      app.ExitButton.Text = 'Exit';
      app.DeletepointButton = uibutton(app.pointMetaEditorUIFigure, 'push');
      app.DeletepointButton.ButtonPushedFcn = createCallbackFcn(app, @DeletepointButtonPushed, true);
      app.DeletepointButton.Tooltip = {'Use this button to delete one selected point'};
      app.DeletepointButton.Text = 'Delete point';
      app.SwappointsButton = uibutton(app.pointMetaEditorUIFigure, 'push');
      app.SwappointsButton.ButtonPushedFcn = createCallbackFcn(app, @SwappointsButtonPushed, true);
      app.SwappointsButton.Enable = 'off';
      app.SwappointsButton.Tooltip = {'Swap two selected points'};
      app.SwappointsButton.Text = 'Swap points';
      app.MergepointsButton = uibutton(app.pointMetaEditorUIFigure, 'push');
      app.MergepointsButton.ButtonPushedFcn = createCallbackFcn(app, @MergepointsButtonPushed, true);
      app.MergepointsButton.Enable = 'off';
      app.MergepointsButton.Tooltip = {'Merge two selected points into a single new point'};
      app.MergepointsButton.Text = 'Merge points';
      app.AddpointButton = uibutton(app.pointMetaEditorUIFigure, 'push');
      app.AddpointButton.ButtonPushedFcn = createCallbackFcn(app, @AddpointButtonPushed, true);
      app.AddpointButton.Text = 'Add point';
      % Show the figure after all components are created
      app.pointMetaEditorUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = pointMetaEditor(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.pointMetaEditorUIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.pointMetaEditorUIFigure)
    end
  end
end