classdef set2Dscaleandorigin < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    set2DscaleandoriginUIFigure  matlab.ui.Figure
    MessagesandinstructionsTextArea  matlab.ui.control.TextArea
    MessagesandinstructionsTextAreaLabel  matlab.ui.control.Label
    ScalelengthEditField         matlab.ui.control.NumericEditField
    ScalelengthEditFieldLabel    matlab.ui.control.Label
    SetoriginButton              matlab.ui.control.Button
    ApplyButton                  matlab.ui.control.Button
    SetscaleButton               matlab.ui.control.Button
    ExitButton                   matlab.ui.control.Button
    Set2DscaleandoriginLabel     matlab.ui.control.Label
    ImageAxes                    matlab.ui.control.UIAxes
  end

  
  properties (Access = private)
    mainApp % handle back to the main DLTdv8a app
    imh % image handle
    originUV % origin coordinates
    originHandle % origin plot handle
    scaleUV % scalebar coordinates
    scaleHandles % scale point handles
    scaleLineHandle % scale line handle
  end
  
  methods (Access = public)
    % not yet   

  end
  
  methods (Access = private)
    
    function [] = originProcessor(app,imgHandle,event)
      % Called by pushing the Set origin button & then clicking on the
      % image


      app.originUV=event.IntersectionPoint(1:2); % grab the location

      % plot the point
      app.originHandle=plot(app.ImageAxes,app.originUV(1),app.originUV(2),'ro','MarkerFaceColor','r','MarkerSize',6);

      % clear the callback
      app.imh.ButtonDownFcn=[];

      % start instructions
      msg={};
      msg{1}=sprintf('XY origin set at pixel coordinate %.1f,%.1f',event.IntersectionPoint(1),event.IntersectionPoint(2));

      % check if the scale is also defined and if so enable the Apply
      % button
      if isempty(app.scaleUV)==false
        app.ApplyButton.Enable=true;
        msg{2}='Now you have origin and scale information defined, the next step is to click the Apply button';
      else
        msg{2}='Next you should click the Set scale button to define the XY scale';
      end
      app.MessagesandinstructionsTextArea.Value=msg;

    end
    
    function [] = scaleProcessor(app,imgHandle,event)
      % Called by pushing the Set scale button & then clicking on the
      % image
      if isempty(app.scaleUV) % this is the first of 2 clicks
        app.scaleUV(1,:)=event.IntersectionPoint(1:2); % grab the location

        % plot the point
        app.scaleHandles(1)=plot(app.ImageAxes,app.scaleUV(1,1),app.scaleUV(1,2),'cd','MarkerSize',6,'LineWidth',2);
        
        % update the instructions
        msg={};
        msg{1}='Click a second time at the other end of the scale length.';
        app.MessagesandinstructionsTextArea.Value=msg;
      else
        app.scaleUV(2,:)=event.IntersectionPoint(1:2); % grab the location
        scalePixels=norm(app.scaleUV(1,:)-app.scaleUV(2,:));

        % plot the point
        app.scaleHandles(2)=plot(app.ImageAxes,app.scaleUV(2,1),app.scaleUV(2,2),'cd','MarkerSize',6,'LineWidth',2);

        % plot the line
        app.scaleLineHandle=plot(app.ImageAxes,app.scaleUV(:,1),app.scaleUV(:,2),'c-');

        % update the instructions
        msg={};
        msg{1}=sprintf('Scale set to %.1f pixels for each distance unit',scalePixels/app.ScalelengthEditField.Value);
        msg{2}='Click the Set scale button again if you need to re-do the inputs';

        % check if the scale is also defined and if so enable the Apply
        % button
        if isempty(app.originUV)==false
          app.ApplyButton.Enable=true;
          msg{3}='Now you have origin and scale information defined, the next step is to click the Apply button';
        else
          msg{3}='The next step is providing the XY origin with the Set origin button';
        end

        app.MessagesandinstructionsTextArea.Value=msg;
      end


    end
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, mainApp)
      % store the handle to the mainApp for use by subfunctions of this app
      app.mainApp=mainApp;

      % grab the current video image from the movie
      axH=app.mainApp.handles{301};
      kids=get(axH,'Children'); % children of current axis
      img=get(kids(end),'CData'); % read current image
      app.imh=imshow(img,'parent',app.ImageAxes);
      hold(app.ImageAxes,'on')

      % set initial text
      msg={};
      msg{1}='This simple interface lets you mark the two end points of a scale bar and set the coordinate origin. It works by creating a set of 2D DLT coefficients, which could then be exported and loaded with subsequent runs of the DLTdv8a tool. You can zoom in and out of the image with the mouse wheel to more accurately set the locations. Start by clicking the Set origin or Set scale buttons';
      app.MessagesandinstructionsTextArea.Value=msg;

    end

    % Button pushed function: ExitButton
    function ExitButtonPushed(app, event)
      %disp('Exit button pushed')
      try
        %app.mainApp.pointEditorHandle(1)=[];
        app.mainApp.subAppObjects{5}=[];
      catch
      end
      delete(app);
    end

    % Button pushed function: SetscaleButton
    function SetscaleButtonPushed(app, event)
      % Called by pushing the Set scale button

      % remove any prior plot points & line
      if isempty(app.scaleHandles)==false
        delete(app.scaleHandles)
      end
      if isempty(app.scaleLineHandle)==false
        delete(app.scaleLineHandle)
      end

      % clear the scale UV clicks information
      app.scaleUV=[];

      % set the callback for clicks
      app.imh.ButtonDownFcn=@app.scaleProcessor;

      % set instructions
      msg={};
      msg{1}='Click once on the image to set the XY coordinate origin. Click the Set origin button again if you need to change the location.';
      app.MessagesandinstructionsTextArea.Value=msg;
      
    end

    % Button pushed function: ApplyButton
    function ApplyButtonPushed(app, event)

      % get the scale length in pixels
      scalePixels=norm(app.scaleUV(1,:)-app.scaleUV(2,:));

      % make a 5-point grid around the origin
      calUV(1,:)=app.originUV;
      calUV(2,:)=app.originUV+[scalePixels,0];
      calUV(3,:)=app.originUV+[-scalePixels,0];
      calUV(4,:)=app.originUV+[0,scalePixels];
      calUV(5,:)=app.originUV+[0,-scalePixels];

      % make the corresponding 5-point
      calXY(1,:)=[0,0];
      calXY(2,:)=[app.ScalelengthEditField.Value,0];
      calXY(3,:)=[-app.ScalelengthEditField.Value,0];
      calXY(4,:)=[0,-app.ScalelengthEditField.Value]; % +Y is "up" in the image, so -V
      calXY(5,:)=[0,app.ScalelengthEditField.Value]; % +Y is "up" in the image, so -V
      
      % compute 2D DLT coefficients
      coefs2d=dlt2d_computeCoefficients(calXY,calUV);

      % send them to the main app and put the main app in 2D DLT mode
      app.mainApp.dltcoef=coefs2d;
      app.mainApp.dltFileName=[pwd,filesep,'scale2D_dltCoefs.csv'];
      app.mainApp.dlt2d=true;
      app.mainApp.dlt=true;

      % recompute the DLT coefficients
      app.mainApp.updateDLTdata(1:app.mainApp.numpts); % update all DLT results
      app.mainApp.fullRedraw();
      app.mainApp.tsWinMakePDstring();

      msg={};
      msg{1}='Applied the scale and origin settings to the project, you can exit this mini-app now.';
      msg{2}='You can also change the Scale or Origin info and re-Apply them to the main app.';
      msg{3}='This calibration is stored as a set of 2D DLT coefficients which can be exported using the main app and loaded into future projects';
      app.MessagesandinstructionsTextArea.Value=msg;


    end

    % Close request function: set2DscaleandoriginUIFigure
    function set2DscaleandoriginUIFigureCloseRequest(app, event)
      % unregister with the main app
      try
        %app.mainApp.pointEditorHandle(1)=[]; % OOPS
        app.mainApp.subAppObjects{5}=[];
      catch
      end
      delete(app)
    end

    % Button pushed function: SetoriginButton
    function SetoriginButtonPushed(app, event)
      %disp('Set origin button pushed')
      % remove any prior plot point
      if isempty(app.originHandle)==false
        delete(app.originHandle)
        app.originUV=[];
      end
      app.imh.ButtonDownFcn=@app.originProcessor;
      
    end

    % Value changed function: ScalelengthEditField
    function ScalelengthEditFieldValueChanged(app, event)
      value = app.ScalelengthEditField.Value;
      if isnan(value) || value<=0
        app.ScalelengthEditField.Value=1;
        msg={};
        msg{1}='Scale values must be positive real numbers. Scale distance was reset to 1.';
        app.MessagesandinstructionsTextArea.Value=msg;
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.set2DscaleandoriginUIFigure = uifigure('Visible', 'off');
      app.set2DscaleandoriginUIFigure.Name = 'MATLAB App';
      app.set2DscaleandoriginUIFigure.CloseRequestFcn = createCallbackFcn(app, @set2DscaleandoriginUIFigureCloseRequest, true);
      app.ImageAxes = uiaxes(app.set2DscaleandoriginUIFigure);
      app.ImageAxes.XTick = [];
      app.ImageAxes.YTick = [];
      app.Set2DscaleandoriginLabel = uilabel(app.set2DscaleandoriginUIFigure);
      app.Set2DscaleandoriginLabel.Text = 'Set 2D scale and origin';
      app.ExitButton = uibutton(app.set2DscaleandoriginUIFigure, 'push');
      app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
      app.ExitButton.Tooltip = {'Exit the point editor interface'};
      app.ExitButton.Text = 'Exit';
      app.SetscaleButton = uibutton(app.set2DscaleandoriginUIFigure, 'push');
      app.SetscaleButton.ButtonPushedFcn = createCallbackFcn(app, @SetscaleButtonPushed, true);
      app.SetscaleButton.Tooltip = {'Use this button to mark the ends of the scale length.'};
      app.SetscaleButton.Text = 'Set scale';
      app.ApplyButton = uibutton(app.set2DscaleandoriginUIFigure, 'push');
      app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
      app.ApplyButton.Enable = 'off';
      app.ApplyButton.Tooltip = {'You can apply the calibration once the "Set scale" and "Set origin" are completed.'};
      app.ApplyButton.Text = 'Apply';
      app.SetoriginButton = uibutton(app.set2DscaleandoriginUIFigure, 'push');
      app.SetoriginButton.ButtonPushedFcn = createCallbackFcn(app, @SetoriginButtonPushed, true);
      app.SetoriginButton.Tooltip = {'Use this button to set the scaled coordinate system origin (0,0)'};
      app.SetoriginButton.Text = 'Set origin';
      app.ScalelengthEditFieldLabel = uilabel(app.set2DscaleandoriginUIFigure);
      app.ScalelengthEditFieldLabel.Tooltip = {'The distance between the two scale bar end points, in whatever distance units you''re using.'};
      app.ScalelengthEditFieldLabel.Text = 'Scale length';
      app.ScalelengthEditField = uieditfield(app.set2DscaleandoriginUIFigure, 'numeric');
      app.ScalelengthEditField.ValueChangedFcn = createCallbackFcn(app, @ScalelengthEditFieldValueChanged, true);
      app.ScalelengthEditField.Tooltip = {'The distance between the two scale bar end points, in whatever distance units you''re using.'};
      app.ScalelengthEditField.Value = 1;
      app.MessagesandinstructionsTextAreaLabel = uilabel(app.set2DscaleandoriginUIFigure);
      app.MessagesandinstructionsTextAreaLabel.Text = 'Messages and instructions';
      app.MessagesandinstructionsTextArea = uitextarea(app.set2DscaleandoriginUIFigure);
      % Show the figure after all components are created
      app.set2DscaleandoriginUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = set2Dscaleandorigin(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.set2DscaleandoriginUIFigure)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.set2DscaleandoriginUIFigure)
    end
  end
end